classdef Launcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RemoveButton_2             matlab.ui.control.Button
        RemoveButton               matlab.ui.control.Button
        AddButton                  matlab.ui.control.Button
        CreatenewdatasetButton     matlab.ui.control.Button
        NewprofileEditField        matlab.ui.control.EditField
        NewprofileEditFieldLabel   matlab.ui.control.Label
        BrowseButton               matlab.ui.control.Button
        SelectdatasetListBox       matlab.ui.control.ListBox
        SelectdatasetListBoxLabel  matlab.ui.control.Label
        LaunchButton               matlab.ui.control.Button
        SelectprofileListBox       matlab.ui.control.ListBox
        SelectprofileListBoxLabel  matlab.ui.control.Label
        LoadsinglepatientButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % Description
    end
    
    methods (Access = public)
        
        function NewDatasetSlot(app, fp)

            app.SelectdatasetListBox.Items{end+1}   = fp;
            app.SelectdatasetListBox.Value          = fp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, datasets, profiles)
            app.mainApp = mainApp;
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
            
            if ~isempty(datasets)
                app.SelectdatasetListBox.Items = datasets;
                app.SelectdatasetListBox.Value = app.SelectdatasetListBox.Items{1};
            end
            if ~isempty(profiles)
                app.SelectprofileListBox.Items = profiles;
                drawnow;
            else
                app.SelectprofileListBox.Items = {''};
                app.SelectprofileListBox.Value = '';
            end
        end

        % Button pushed function: BrowseButton
        function BrowseDataset(app, event)
            fp = uigetdir('Load existing dataset');

            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            if ~isempty(fp) && ...
                    ~any(ismember(app.SelectdatasetListBox.Items, fp))

                app.SelectdatasetListBox.Items{end+1}   = fp;
                app.SelectdatasetListBox.Value          = fp;
            end
        end

        % Button pushed function: LoadsinglepatientButton
        function LoadSingle(app, event)
            
        end

        % Button pushed function: CreatenewdatasetButton
        function CreateNewDataset(app, event)
            NewDataset(app)
        end

        % Button pushed function: LaunchButton
        function Launch(app, event)
            app.UIFigure.Visible = 'off';

            if isempty(app.SelectprofileListBox.Value)
                profile = '';
            else
                profile = app.SelectprofileListBox.Value;
            end

            app.mainApp.LaunchFromLauncher(...
                app.SelectdatasetListBox.Items, ...
                app.SelectprofileListBox.Items, ...
                app.SelectdatasetListBox.Value, ...
                profile)

            close(app.UIFigure);
        end

        % Button pushed function: AddButton
        function AddProfile(app, event)
            profile = app.NewprofileEditField.Value;
            if ~isempty(profile) && ...
                    ~any(ismember(app.SelectprofileListBox.Items, profile))
                
                app.SelectprofileListBox.Items{end+1}   = profile;
                app.SelectprofileListBox.Value          = profile;
            end
        end

        % Button pushed function: RemoveButton
        function RemoveProfile(app, event)
            idx = -1;
            for i = 1:length(app.SelectprofileListBox.Items)
                item = app.SelectprofileListBox.Items{i};
                if strcmp(item, app.SelectprofileListBox.Value)
                    idx = i;
                    break
                end
            end

            if idx == -1
                return
            end

            app.SelectprofileListBox.Items(idx) = [];
        end

        % Button pushed function: RemoveButton_2
        function RemoveDataset(app, event)
            idx = -1;
            for i = 1:length(app.SelectdatasetListBox.Items)
                item = app.SelectdatasetListBox.Items{i};
                if strcmp(item, app.SelectdatasetListBox.Value)
                    idx = i;
                    break
                end
            end

            if idx == -1
                return
            end

            app.SelectdatasetListBox.Items(idx) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadsinglepatientButton = uibutton(app.UIFigure, 'push');
            app.LoadsinglepatientButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSingle, true);
            app.LoadsinglepatientButton.Text = 'Load single patient';
            app.SelectprofileListBoxLabel = uilabel(app.UIFigure);
            app.SelectprofileListBoxLabel.Text = 'Select profile';
            app.SelectprofileListBox = uilistbox(app.UIFigure);
            app.SelectprofileListBox.Items = {};
            app.SelectprofileListBox.Value = {};
            app.LaunchButton = uibutton(app.UIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @Launch, true);
            app.LaunchButton.Text = 'Launch';
            app.SelectdatasetListBoxLabel = uilabel(app.UIFigure);
            app.SelectdatasetListBoxLabel.Text = 'Select dataset';
            app.SelectdatasetListBox = uilistbox(app.UIFigure);
            app.SelectdatasetListBox.Items = {};
            app.SelectdatasetListBox.Value = {};
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataset, true);
            app.BrowseButton.Text = 'Browse';
            app.NewprofileEditFieldLabel = uilabel(app.UIFigure);
            app.NewprofileEditFieldLabel.Text = 'New profile';
            app.NewprofileEditField = uieditfield(app.UIFigure, 'text');
            app.CreatenewdatasetButton = uibutton(app.UIFigure, 'push');
            app.CreatenewdatasetButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewDataset, true);
            app.CreatenewdatasetButton.Text = 'Create new dataset';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddProfile, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveProfile, true);
            app.RemoveButton.Text = 'Remove';
            app.RemoveButton_2 = uibutton(app.UIFigure, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveDataset, true);
            app.RemoveButton_2.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Launcher(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end