classdef NewDataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CancelButton           matlab.ui.control.Button
        FinishButton           matlab.ui.control.Button
        ComputeADCButtonGroup  matlab.ui.container.ButtonGroup
        Option1_2              matlab.ui.control.RadioButton
        Option2_2              matlab.ui.control.RadioButton
        fnLabel_2              matlab.ui.control.Label
        fnLabel                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        BrowseButton           matlab.ui.control.Button
        CreateButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % app from which NewDataset is launched
        step    % Which step in the process are we
    end
    
    properties (Access = public)
        fp      % filepath to dataset
        dlg     % updatedialogue
    end
    
    methods (Access = private)
        
        function Reset(app)

            pause(0.2)
            close(app.dlg)
            app.BrowseButton.Enable = 'on'; 
            app.CreateButton.Enable = 'off';
            app.fp = '';
            app.fnLabel.Text = app.fp;
            app.ComputeADCButtonGroup.Enable = 'off';

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin == 2
                app.mainApp = varargin{1};
            end
            app.step = 0;
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPressed(app, event)
            filepath = uigetdir('Load existing dataset');
            if filepath ~= 0
                app.fnLabel.Text = filepath;
                app.fp  = filepath;
                app.CreateButton.Enable = 'on';
                app.ComputeADCButtonGroup.Enable = 'on';
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: CreateButton
        function CreateButtonPressed(app, event)
                
            
            app.CancelButton.Enable = 'off';  
            app.BrowseButton.Enable = 'off';
            app.ComputeADCButtonGroup.Enable = 'off';
            app.CreateButton.Enable = 'off';

            app.dlg = uiprogressdlg(app.UIFigure, 'Title',...
                'Creating dataset - this will take a while');

            status = NewDatasetUtils.ConvertDcm2Nii(app);

            % if status                
            %     status = NewDatasetUtils.ResliceNiis(app);
            % else
            %     app.Reset()
            %     return
            % end

            if status 
                if app.Option2_2.Value
                    status = NewDatasetUtils.ComputeADCMaps(app);
                end
            else
                app.Reset()
                msgbox("Something went wrong.")
                return
            end

            if ~status
                app.Reset()
                return
            end

            pause(0.2)
            close(app.dlg)
            app.Label.Text = 'Finished creating dataset';
            
            app.FinishButton.Enable = 'on';


        end

        % Button pushed function: FinishButton
        function FinishButtonPressed(app, event)
            app.mainApp.NewDatasetSlot(app.fp)
            close(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPressed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Create new dataset';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPressed, true);
            app.CreateButton.Enable = 'off';
            app.CreateButton.Text = 'Create';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPressed, true);
            app.BrowseButton.Text = 'Browse';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Please supply a base directory where each imaging session is stored in its own subdirectory.';
            app.fnLabel = uilabel(app.UIFigure);
            app.fnLabel.Text = '';
            app.fnLabel_2 = uilabel(app.UIFigure);
            app.fnLabel_2.Text = 'Path:';
            app.ComputeADCButtonGroup = uibuttongroup(app.UIFigure);
            app.ComputeADCButtonGroup.Enable = 'off';
            app.ComputeADCButtonGroup.Title = 'Compute new ADC maps?';
            app.Option2_2 = uiradiobutton(app.ComputeADCButtonGroup);
            app.Option2_2.Text = 'Yes - recommended';
            app.Option2_2.Value = true;
            app.Option1_2 = uiradiobutton(app.ComputeADCButtonGroup);
            app.Option1_2.Text = 'No';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPressed, true);
            app.FinishButton.Enable = 'off';
            app.FinishButton.Text = 'Finish';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPressed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewDataset(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end