classdef Preprocessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MultipleimagesCheckBox        matlab.ui.control.CheckBox
        OutputLabel2                  matlab.ui.control.Label
        InputLabel2                   matlab.ui.control.Label
        OutputLabel                   matlab.ui.control.Label
        InputLabel                    matlab.ui.control.Label
        ProcessButton                 matlab.ui.control.Button
        SelectoutputfolderButton      matlab.ui.control.Button
        SelectdataButton              matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        Dcm2niiTab                    matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        PhilipsprecisefloatCheckBox   matlab.ui.control.CheckBox
        SeriesinnameCheckBox          matlab.ui.control.CheckBox
        DescriptioninnameCheckBox     matlab.ui.control.CheckBox
        CorrectionsTab                matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        SaveFaabsFECheckBox           matlab.ui.control.CheckBox
        SaveFECheckBox                matlab.ui.control.CheckBox
        SaveRDCheckBox                matlab.ui.control.CheckBox
        SaveL1CheckBox                matlab.ui.control.CheckBox
        SaveFACheckBox                matlab.ui.control.CheckBox
        SaveMDCheckBox                matlab.ui.control.CheckBox
        SaveasniiCheckBox             matlab.ui.control.CheckBox
        MotionEddycorrectionCheckBox  matlab.ui.control.CheckBox
        DKIDTIfitCheckBox             matlab.ui.control.CheckBox
        DenoiseCheckBox               matlab.ui.control.CheckBox
        ResliceTab                    matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        CreatermsstudiofolderButton   matlab.ui.control.Button
        ResampleisotropicCheckBox     matlab.ui.control.CheckBox
        ADCTab                        matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
    end

    
    properties (Access = public)
        filepath % location of input files
        outpath  % location of output files
        t1_path  % location of T1 to register to
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectdataButton
        function slctDataPressed(app, event)
            PrePrcIO.SelectData(app)
        end

        % Button pushed function: SelectoutputfolderButton
        function SlctOutPressed(app, event)
            PrePrcIO.SelectOutPath(app)
        end

        % Button pushed function: ProcessButton
        function StartPrePrcPressed(app, event)
            
            switch app.TabGroup.SelectedTab.Title
                case 'Dcm2nii'
                    PrePrcDcm2nii.Start(app)
                case 'Corrections'
                    PrePrcCorr.Start(app)
                case 'Reslice'
                    PrePrcReslice.Start(app)
                case 'ADC'
                    PrePrcADC.Start(app)
            end

%             app.filepath = '';
%             app.outpath  = '';
%             app.ProcessButton.Enable = false;

        end

        % Callback function
        function RegisterChanged(app, event)
            value = app.RegistertostructuralCheckBox.Value;
            if value
                PrePrcIO.SelectT1(app)
            end
        end

        % Value changed function: DKIDTIfitCheckBox
        function DKIDTICheckBoxChanged(app, event)
            value = app.DKIDTIfitCheckBox.Value;
            app.MotionEddycorrectionCheckBox.Enable = value;
            app.SaveasniiCheckBox.Enable = value;
        end

        % Value changed function: SaveasniiCheckBox
        function SaveNiiChecked(app, event)
            value = app.SaveasniiCheckBox.Value;
            app.SaveFACheckBox.Visible = value;
            app.SaveMDCheckBox.Visible = value;
            app.SaveFECheckBox.Visible = value;
            app.SaveFaabsFECheckBox.Visible = value;
            app.SaveL1CheckBox.Visible = value;
            app.SaveRDCheckBox.Visible = value;

        end

        % Button pushed function: CreatermsstudiofolderButton
        function CreateRMSStudioFolderPressed(app, event)
            if isempty(app.filepath)
                return
            end

            PrePrcIO.makeRMSStudioDir(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Dcm2niiTab = uitab(app.TabGroup);
            app.Dcm2niiTab.Title = 'Dcm2nii';
            app.GridLayout2 = uigridlayout(app.Dcm2niiTab);
            app.DescriptioninnameCheckBox = uicheckbox(app.GridLayout2);
            app.DescriptioninnameCheckBox.Text = 'Description in name';
            app.DescriptioninnameCheckBox.Value = true;
            app.SeriesinnameCheckBox = uicheckbox(app.GridLayout2);
            app.SeriesinnameCheckBox.Text = 'Series in name';
            app.SeriesinnameCheckBox.Value = true;
            app.PhilipsprecisefloatCheckBox = uicheckbox(app.GridLayout2);
            app.PhilipsprecisefloatCheckBox.Text = 'Philips precise float';
            app.PhilipsprecisefloatCheckBox.Value = true;
            app.CorrectionsTab = uitab(app.TabGroup);
            app.CorrectionsTab.Title = 'Corrections';
            app.GridLayout = uigridlayout(app.CorrectionsTab);
            app.DenoiseCheckBox = uicheckbox(app.GridLayout);
            app.DenoiseCheckBox.Text = 'Denoise';
            app.DKIDTIfitCheckBox = uicheckbox(app.GridLayout);
            app.DKIDTIfitCheckBox.ValueChangedFcn = createCallbackFcn(app, @DKIDTICheckBoxChanged, true);
            app.DKIDTIfitCheckBox.Text = 'DKI / DTI fit';
            app.MotionEddycorrectionCheckBox = uicheckbox(app.GridLayout);
            app.MotionEddycorrectionCheckBox.Enable = 'off';
            app.MotionEddycorrectionCheckBox.Text = 'Motion / Eddy correction';
            app.SaveasniiCheckBox = uicheckbox(app.GridLayout);
            app.SaveasniiCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveNiiChecked, true);
            app.SaveasniiCheckBox.Enable = 'off';
            app.SaveasniiCheckBox.Text = 'Save as .nii';
            app.SaveMDCheckBox = uicheckbox(app.GridLayout);
            app.SaveMDCheckBox.Visible = 'off';
            app.SaveMDCheckBox.Text = 'Save MD';
            app.SaveFACheckBox = uicheckbox(app.GridLayout);
            app.SaveFACheckBox.Visible = 'off';
            app.SaveFACheckBox.Text = 'Save FA';
            app.SaveL1CheckBox = uicheckbox(app.GridLayout);
            app.SaveL1CheckBox.Visible = 'off';
            app.SaveL1CheckBox.Text = 'Save L1';
            app.SaveRDCheckBox = uicheckbox(app.GridLayout);
            app.SaveRDCheckBox.Visible = 'off';
            app.SaveRDCheckBox.Text = 'Save RD';
            app.SaveFECheckBox = uicheckbox(app.GridLayout);
            app.SaveFECheckBox.Visible = 'off';
            app.SaveFECheckBox.Text = 'Save FE';
            app.SaveFaabsFECheckBox = uicheckbox(app.GridLayout);
            app.SaveFaabsFECheckBox.Visible = 'off';
            app.SaveFaabsFECheckBox.Text = 'Save Fa abs FE';
            app.ResliceTab = uitab(app.TabGroup);
            app.ResliceTab.Title = 'Reslice';
            app.GridLayout3 = uigridlayout(app.ResliceTab);
            app.ResampleisotropicCheckBox = uicheckbox(app.GridLayout3);
            app.ResampleisotropicCheckBox.Text = 'Resample isotropic';
            app.CreatermsstudiofolderButton = uibutton(app.GridLayout3, 'push');
            app.CreatermsstudiofolderButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRMSStudioFolderPressed, true);
            app.CreatermsstudiofolderButton.Text = 'Create rmsstudio folder';
            app.ADCTab = uitab(app.TabGroup);
            app.ADCTab.Title = 'ADC';
            app.GridLayout4 = uigridlayout(app.ADCTab);
            app.SelectdataButton = uibutton(app.UIFigure, 'push');
            app.SelectdataButton.ButtonPushedFcn = createCallbackFcn(app, @slctDataPressed, true);
            app.SelectdataButton.Text = 'Select data';
            app.SelectoutputfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SlctOutPressed, true);
            app.SelectoutputfolderButton.Text = 'Select output folder';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartPrePrcPressed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input: ';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.InputLabel2 = uilabel(app.UIFigure);
            app.InputLabel2.Text = '';
            app.OutputLabel2 = uilabel(app.UIFigure);
            app.OutputLabel2.Text = '';
            app.MultipleimagesCheckBox = uicheckbox(app.UIFigure);
            app.MultipleimagesCheckBox.Text = 'Multiple images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end