classdef RMSStudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        LoadLabelsMenu                matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        StudyMenu                     matlab.ui.container.Menu
        SwitchUserMenu                matlab.ui.container.Menu
        LoadDatabaseMenu              matlab.ui.container.Menu
        NextStudyMenu                 matlab.ui.container.Menu
        PreviousStudyMenu             matlab.ui.container.Menu
        ResetStudyMenu                matlab.ui.container.Menu
        LoadROIpointsMenu             matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        CoronalMenu                   matlab.ui.container.Menu
        SagittalMenu                  matlab.ui.container.Menu
        AxialMenu                     matlab.ui.container.Menu
        SwitchViewMenu                matlab.ui.container.Menu
        View1Menu                     matlab.ui.container.Menu
        View2Menu                     matlab.ui.container.Menu
        SwitchColoursMenu             matlab.ui.container.Menu
        AnalyseMenu                   matlab.ui.container.Menu
        Labels3DMenu                  matlab.ui.container.Menu
        LabelsStatsMenu               matlab.ui.container.Menu
        DrawMenu                      matlab.ui.container.Menu
        LabelsMenu                    matlab.ui.container.Menu
        DrawPolygonMenu               matlab.ui.container.Menu
        EditPolygonMenu               matlab.ui.container.Menu
        MeasurementsMenu              matlab.ui.container.Menu
        LineMenu                      matlab.ui.container.Menu
        EllipseMenu                   matlab.ui.container.Menu
        ClearMenu                     matlab.ui.container.Menu
        UndoMenu                      matlab.ui.container.Menu
        RemoveSingleMenu              matlab.ui.container.Menu
        RemoveAllMenu                 matlab.ui.container.Menu
        SelectionSensitivityMenu      matlab.ui.container.Menu
        SegmentMenu                   matlab.ui.container.Menu
        MagicDrawMenu                 matlab.ui.container.Menu
        MagicDraw3DMenu               matlab.ui.container.Menu
        PointstoSegmentationMenu      matlab.ui.container.Menu
        Settings                      matlab.ui.container.Menu
        UIAxes1                       matlab.ui.control.UIAxes
        CoronalButton                 matlab.ui.control.Button
        SagittalButton                matlab.ui.control.Button
        AxialButton                   matlab.ui.control.Button
        SliceSliderLabel              matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        MinvalSliderLabel             matlab.ui.control.Label
        MinvalSlider                  matlab.ui.control.Slider
        MaxvalSliderLabel             matlab.ui.control.Label
        MaxvalSlider                  matlab.ui.control.Slider
        ViewcontrolLabel              matlab.ui.control.Label
        BrightnessandslicemgmtLabel   matlab.ui.control.Label
        SensitivitySliderLabel        matlab.ui.control.Label
        SensitivitySlider             matlab.ui.control.Slider
        AvailableimagesListBoxLabel   matlab.ui.control.Label
        AvailableimagesListBox        matlab.ui.control.ListBox
        MagicdrawoptionsLabel         matlab.ui.control.Label
        AlgorithmDropDownLabel        matlab.ui.control.Label
        AlgorithmDropDown             matlab.ui.control.DropDown
        StatusLampLabel               matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        SliceDecreaseButton           matlab.ui.control.Button
        SliceIncreaseButton           matlab.ui.control.Button
        Decrease4DButton              matlab.ui.control.Button
        Increase4DButton              matlab.ui.control.Button
        DSliderLabel                  matlab.ui.control.Label
        DSlider                       matlab.ui.control.Slider
        View1Button                   matlab.ui.control.Button
        View2Button                   matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        ROIsLabel                     matlab.ui.control.Label
        ROIBox                        matlab.ui.control.ListBox
        UIAxes1Label                  matlab.ui.control.Label
        UIAxes2Label                  matlab.ui.control.Label
        AvailableStudiesListBoxLabel  matlab.ui.control.Label
        AvailableStudiesListBox       matlab.ui.control.ListBox
        DrawPolygonButton             matlab.ui.control.Button
        DrawLabel                     matlab.ui.control.Label
        EditPolygonButton             matlab.ui.control.Button
        AlignLRButton                 matlab.ui.control.Button
        AlignRLButton                 matlab.ui.control.Button
        AlignLabel                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        %GUI
        drawing,                    % All the objects needed to draw the measurements and
                                    % ROIs on the screen. Only stores items
                                    % for the current view(s)
        current_slice,              % Slice of the image (in focus) that is currently displayed 
        colors_list,                % List of colors used to differentiate all the user-defined objects
        current_folder,             % Path to study location on disk
        current_image,              % ???
        current_view,               % View which is currently in Focus
        current_image_idx,          % Index of the image on the view which is currently in focus
        current_4d_idx,             % ??
        view_axis,                  % Coronal = 1, Sagittal = 2, Axial = 3
        MinValue,                   % Minimum image value (everything <= MinValue -> 0)
        MaxValue,                   % Maximum image value (everything >= MaxValue -> 255)
        cMinValue,                  % Minimum display value
        cMaxValue,                  % Maximum display value
        selector_size               % size of window when deleting objects
        mouse_pos                   % mouse position in events
        should_show_selection,      % ???
        selection_contour           % ???
        
        %Study
        segmentation_list,          % Stores the segmentations 
        seg_names_list,             % Stores the segmentation names 
        roiPointList                % Stores the roiPoints
        roiPointIndexList           % Stores the roiPointIndexes
        measurement_list,           % Stores all the measurements 
        measure_names_list,         % Stores all the measurement names 
        measure_length_list,        % Stores all the measurement lengths
        backup_list                 % Stores a backup of all previous 
       
        %Views
        data,                       % The image of the view in focus
        segmentation,               % Segmentations of the image in focus
        seg_names,                  % Names of all the segmentation objects
        measure_names,              % Names of all the measurement objects
        measure_length,             % Length of all the measurement objects
        roiPoints                   % All the points drawn in app.drawing.points
        roiPointIndex               %Stores the segmentation that all the 
                                    % ROI points belong to.
        dragPoint                   %Stores the location from where to 
                                    % start dragging roi points.
        currentDragPoint            %Stores which point is currently being
                                    % dragged
        
        %Other
        image_per_view              %Stores which image is used per view
        slice_per_image             %Stores which slice is used per view
        user_profile                %Stores which user is currently working
        dataset                     %Stores the list of study directories.
        filepath                    %Stores the current directory
        zoomToggle                  %Stores zoomstatus
        Align                       %Stores align status (lr, rl)
        
        
    end
    
    methods (Access = public)
    
        % Just an wrapper for convenience
        function MouseClickedInImage(app,im,hit)
            Interaction.MouseClickedInImage(app, hit); 
        end
        
        function MouseDraggedInImage(app, im, hit)
            Interaction.MouseDraggedInImage(app, hit);
        end
        
        function MouseReleasedInImage(app, im, hit)
            Interaction.MouseReleasedInImage(app, hit)
        end
        
        % Automatically updates the correct axis. Eases the creation of more.
        function UpdateImage(app)
            string = sprintf('the_axes = app.UIAxes%1d;',(app.current_view));
            eval(string);
            Graphics.UpdateImageForAxis(app,the_axes);
        end    
        
        function UpdateUserObj(app)
            string = sprintf('the_axes = app.UIAxes%1d;',(app.current_view));
            eval(string);
            Graphics.UpdateUserObjectsForAxis(app,the_axes);
        end
        
        % Update the slice and 4D sliders labels
        function UpdateSliceSlider(app)
        
            if(isempty(app.data))
                return
            end
            
            %Get data dimensions
            refvol = app.data.img;
            
            %Update SliceSlider
            app.SliceSlider.Limits = [1 size(refvol,app.view_axis)];
            max_ticks = 4;
            step = round(size(refvol,app.view_axis)/(max_ticks-1));
            app.SliceSlider.MajorTicks = [1:step:size(refvol,app.view_axis) size(refvol,app.view_axis)];
            app.SliceSlider.MinorTicks = 1:1:size(refvol,app.view_axis);
            app.SliceSlider.Value = double(app.current_slice);
            
            
            %Update dimensionSlider
            app.DSlider.MajorTicks = 1:1:size(refvol,4);
            if(size(refvol,4) > 20)
                app.DSlider.MajorTicks = 1:10:size(refvol,4);
            end
            if(size(refvol,4) > 1)
                app.DSlider.Limits = [1 size(refvol,4)];
            else
                app.DSlider.Limits = [1 2];
            end
            app.DSlider.Value = double(app.current_4d_idx);
            
        end
    
        % Update the Min-Max slider labels
        function UpdateMinMaxSlider(app)
            if ~isfield(app.data, 'img')
                return
            end
%             V = app.data_list{app.current_image(app.current_image_idx)}.img(:,:,:,1);
            V = app.data.img(:,:,:,1);
            app.MinValue = min(V(:));
            if app.MinValue <0
                app.MinValue = 0;
            end
            app.MaxValue = max(V(:));
            app.cMinValue = app.MinValue;
            app.cMaxValue = app.MaxValue;
            if(app.MinValue == app.MaxValue)
                app.MinValue = 0;
                app.MaxValue = 100;
            end
%             max_ticks = 6;
%             step = round((100)/max_ticks);
            app.MinvalSlider.MajorTicks = [0 50 100];%app.MinValue:step:app.MaxValue;
            app.MinvalSlider.Value = double(floor(app.MinValue/app.MaxValue*100));
            app.MaxvalSlider.MajorTicks = [0 50 100];%app.MinValue:step:app.MaxValue;
            app.MaxvalSlider.Value = double(floor(app.MaxValue/app.MaxValue*100));
        end
    
        function h = SetupAxis(app, the_ax)
            rect = get(the_ax,'OuterPosition');
            bg = zeros(rect([4 3]));
            bg(round(end/2),round(end/2)) = 1;
            h = imagesc(the_ax,bg);
            colormap(the_ax,'gray');
            axis(the_ax,'off');
            the_ax.BackgroundColor = 'k';
        end
            
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0, 'DefaultFigureRenderer', 'opengl');
%             rng(15061988);
            
            h1   = app.SetupAxis(app.UIAxes1);
            h2   = app.SetupAxis(app.UIAxes2);            
            set(h1,'ButtonDownFcn',@app.MouseClickedInImage);
            set(h2,'ButtonDownFcn',@app.MouseClickedInImage);
            
            app.selector_size = 1;
            app.drawing.active              = false;
            app.drawing.magic               = false;
            app.drawing.magic_sensitivity   = 2;
            app.drawing.magic_3d            = false;
            app.drawing.magic_method        = 1;
            app.drawing.measure_line        = false;
            app.drawing.edit                = false;
            opengl('hardware');
            app.colors_list                 = zeros(1000,3);
            for ij=1:size(app.colors_list,1)
                app.colors_list(ij,:)       = [randi(100)/100 randi(100)/100 randi(100)/100];
                app.colors_list(ij,:)       = app.colors_list(ij,:)/norm(app.colors_list(ij,:));
            end
            app.StatusLampLabel.Text        = 'Idle';
            app.StatusLamp.Color            = 'g';
            app.current_image_idx           = 1;
            app.current_view                = 1;
            app.View1Button.BackgroundColor = [.96 .96 0];
            
            %init data storage
            app.image_per_view              = [1,1];
            app.slice_per_image             = {};
            [folder, ~, ~]  = fileparts(which('RMSStudio'));
            nii_path        = [folder '\nifti'];
            addpath(nii_path);
            
        end

        % Menu selected function: LoadMenu
        function LoadButtonPushed(app, event)
            IOUtils.PrepareStudy(app);
        end

        % Callback function: AxialButton, AxialMenu
        function AxialButtonPushed(app, event)
            app.view_axis = 3;
            if(app.current_slice > size(app.data.img,3))
                app.current_slice = round(size(app.data.img,3)/2);
            end
            app.UpdateSliceSlider();
            app.UpdateImage();
            app.AxialButton.BackgroundColor = [.96 .96 0];
            app.SagittalButton.BackgroundColor = [.96 .96 .96];
            app.CoronalButton.BackgroundColor = [.96 .96 .96];
        end

        % Callback function: SagittalButton, SagittalMenu
        function SagittalButtonPushed(app, event)
            app.view_axis = 2;
            if(app.current_slice > size(app.data.img,2))
                app.current_slice = round(size(app.data.img,2)/2);
            end
            app.UpdateSliceSlider();
            app.UpdateImage();
            app.AxialButton.BackgroundColor = [.96 .96 .96];
            app.SagittalButton.BackgroundColor = [.96 .96 0];
            app.CoronalButton.BackgroundColor = [.96 .96 .96];
        end

        % Callback function: CoronalButton, CoronalMenu
        function CoronalButtonPushed(app, event)
            app.view_axis = 1;
            if(app.current_slice > size(app.data.img,1))
                app.current_slice = round(size(app.data.img,1)/2);
            end
            app.UpdateSliceSlider();
            app.UpdateImage();
            app.AxialButton.BackgroundColor = [.96 .96 .96];
            app.SagittalButton.BackgroundColor = [.96 .96 .96];
            app.CoronalButton.BackgroundColor = [.96 .96 0];
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            Interaction.UpdateSlice(app, changingValue)
        end

        % Menu selected function: RemoveSingleMenu
        function SelectDeleteButtonPushed(app, event)
            if(~isfield(app.segmentation,'img'))
                return
            end
            SL_D = app.should_show_selection;
            GUI.DisableAllButtonsAndActions(app);
            if(SL_D == false)
                app.should_show_selection = true;
                app.SelectDeleteButton.BackgroundColor = [.96 .96 0];
            else
                app.should_show_selection = false;
            end
            app.UpdateImage();
            Graphics.UpdateSelectionContour(app);
        end

        % Menu selected function: SaveMenu
        function SaveeditedsegmButtonPushed(app, event)
            Interaction.Save(app);
        end

        % Value changing function: MaxvalSlider
        function MaxvalSliderValueChanging(app, event)
            changingValue = event.Value;
            next_maxvalue = round(changingValue/100*app.MaxValue);
            if(next_maxvalue > app.cMinValue)
                app.cMaxValue = next_maxvalue;
            else
%                 app.MaxvalSlider.Value = round(app.cMinValue);
            end
            app.UpdateImage();
        end

        % Value changing function: MinvalSlider
        function MinvalSliderValueChanging(app, event)
            changingValue = event.Value;
            next_minvalue = round(changingValue/100*app.MaxValue);
            if(next_minvalue < app.cMaxValue)
                app.cMinValue = next_minvalue;
            else
%                 app.MinvalSlider.Value = round(app.cMaxValue);
            end
            app.UpdateImage();
        end

        % Menu selected function: UndoMenu
        function UndoButtonPushed(app, event)
            %             app.drawing.magic = false;
            %             app.drawing.active = false;
            %             app.DrawpolygonButton.BackgroundColor = [.96 .96 .96];
            %             app.MagicdrawButton.BackgroundColor = [.96 .96 .96];
            Interaction.Undo(app)
        end

        % Menu selected function: SelectionSensitivityMenu
        function SelectionsensitivityButtonPushed(app, event)
            answer = inputdlg(['Please input the new sensitivity in pixel, (current ' num2str(app.selector_size) ')']);
            if(isempty(answer))
                return
            end
            answer = answer{1};
            if(ischar(answer))
                answer = str2double(answer);
            end
            if(answer > 0 && answer <= 10)
                app.selector_size = answer;
            else
                uialert(uifigure,'Invalid values! 0 < x <= 10 pixels','');
            end
        end

        % Callback function: DrawPolygonButton, DrawPolygonMenu
        function DrawpolygonButtonPushed(app, event)
            Interaction.DrawPolygon(app)
        end

        % Menu selected function: RemoveAllMenu
        function DeleteROIsALLButtonPushed(app, event)
        
            Interaction.DeleteROIsAndMeasurements(app)
        
        end

        % Menu selected function: MagicDrawMenu
        function MagicdrawButtonPushed(app, event)
            Interaction.MagicDraw(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if(~isfield(app.data,'img') || isempty(app.data.img))
                return
            end
            if(strcmp(key,'uparrow'))
                app.current_slice = app.current_slice + 1;
                if(app.current_slice < 1)
                    app.current_slice = 1;
                end
                if(app.current_slice > size(app.data.img,app.view_axis))
                    app.current_slice = size(app.data.img,app.view_axis);
                end
                app.SliceSlider.Value = app.current_slice;%/size(app.data.img,app.view_axis)*100;
                app.UpdateImage();
            elseif(strcmp(key,'downarrow'))
                app.current_slice = app.current_slice - 1;
                if(app.current_slice < 1)
                    app.current_slice = 1;
                end
                if(app.current_slice > size(app.data.img,app.view_axis))
                    app.current_slice = size(app.data.img,app.view_axis);
                end
                app.SliceSlider.Value = app.current_slice;%/size(app.data.img,app.view_axis)*100;
                app.UpdateImage();
            elseif(strcmp(key, 'backspace'))
                Interaction.BackspacePressed(app)
            elseif(strcmp(key, 'z'))
                Interaction.ToggleZoom(app)
            end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            if(~isfield(app.data,'img') || isempty(app.data.img))
                return
            end
            
            %Increase slice
            if(verticalScrollAmount > 0 && verticalScrollCount < 0)
                app.current_slice = app.current_slice + 1;
                if(app.current_slice < 1)
                    app.current_slice = 1;
                end
                if(app.current_slice > size(app.data.img,app.view_axis))
                    app.current_slice = size(app.data.img,app.view_axis);
                end
                app.SliceSlider.Value = app.current_slice;%/size(app.data.img,app.view_axis)*100;
                app.UpdateImage();
                
            %Decrease slice
            elseif(verticalScrollAmount > 0 && verticalScrollCount > 0)
                app.current_slice = app.current_slice - 1;
                if(app.current_slice < 1)
                    app.current_slice = 1;
                end
                if(app.current_slice > size(app.data.img,app.view_axis))
                    app.current_slice = size(app.data.img,app.view_axis);
                end
                app.SliceSlider.Value = app.current_slice;%/size(app.data.img,app.view_axis)*100;
                app.UpdateImage();
            end
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            value = app.SensitivitySlider.Value;
            value = round(value);
            app.SensitivitySlider.Value = value;
            app.drawing.magic_sensitivity = value;
        end

        % Callback function
        function DCheckBoxValueChanged(app, event)
            value = app.DCheckBox.Value;
            app.drawing.magic_3d = value;
        end

        % Value changed function: AvailableimagesListBox
        function AvailableimagesListBoxValueChanged(app, event)
            %create backup
            Backups.CreateBackup(app)
            Interaction.ListBoxChanged(app)
        end

        % Menu selected function: ResetStudyMenu
        function ResetstudyButtonPushed(app, event)
            disp(app.current_folder);
            GUI.DisableControlsStatus(app);
            app.UIFigure.Visible = 'off';
            drawnow;
            answer = questdlg('This will reset this study folder, are you sure?', ...
               	'Study reset', ...
               	'Yes','No','No');
            if(strcmp(answer,'Yes') > 0)
                delete(fullfile(app.current_folder,'*rmsstudio*'));
                GUI.DisableControlsStatus(app);
                app.LoadButton.Enable = 'on';
            else
                GUI.RevertControlsStatus(app);
            end
            GUI.DisableAllButtonsAndActions(app);
            app.UIFigure.Visible = 'on';
        end

        % Callback function
        function AlignlabelsButtonPushed(app, event)
            IOUtils.SaveSegmentations(app);
            Interaction.RegisterSelectedImageToDialog(app);
            GUI.DisableAllButtonsAndActions(app);
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            for ij=1:length(app.AlgorithmDropDown.Items)
                if(strcmp(value, app.AlgorithmDropDown.Items{ij}) > 0)
                    break
                end
            end
            app.drawing.magic_method = ij;
        end

        % Menu selected function: LineMenu
        function MeasureLineButtonPushed(app, event)
            DP_D = app.drawing.measure_line;
            GUI.DisableAllButtonsAndActions(app);
            if(DP_D == false)
                app.drawing.measure_line = true;
%                 app.MeasureLineButton.BackgroundColor = [.96 .96 0];
            end
        end

        % Button pushed function: SliceDecreaseButton
        function SliceDecreaseButtonPushed(app, event)
            Interaction.UpdateSlice(app, app.current_slice - 1);
        end

        % Button pushed function: SliceIncreaseButton
        function SliceIncreaseButtonPushed(app, event)
            Interaction.UpdateSlice(app, app.current_slice + 1);    
        end

        % Menu selected function: SwitchColoursMenu
        function ShufflecolorsButtonPushed(app, event)
            IX = randperm(size(app.colors_list,1));
            app.colors_list = app.colors_list(IX,:);
            app.UpdateImage();
            Graphics.UpdateSelectionContour(app);
        end

        % Menu selected function: EllipseMenu
        function MeasureAutoButtonPushed(app, event)
           Interaction.PerformAutomaticEllipseMeasurement(app);
        end

        % Button pushed function: Decrease4DButton
        function Decrease4DButtonPushed(app, event)
            if(app.current_4d_idx > 1)
                app.current_4d_idx = app.current_4d_idx - 1;
            end
            app.DSlider.Value = app.current_4d_idx;
            app.UpdateImage();
            Graphics.UpdateSelectionContour(app);
        end

        % Button pushed function: Increase4DButton
        function Increase4DButtonPushed(app, event)
            if(app.current_4d_idx < size(app.data.img,4))
                app.current_4d_idx = app.current_4d_idx + 1;
            end
            app.DSlider.Value = app.current_4d_idx;
            app.UpdateImage();
            Graphics.UpdateSelectionContour(app);            
        end

        % Value changing function: DSlider
        function DSliderValueChanging(app, event)
            changingValue = event.Value;
            app.current_4d_idx = round(changingValue);
            app.UpdateImage();
            Graphics.UpdateSelectionContour(app);                        
        end

        % Callback function: View1Button, View1Menu
        function View1ButtonPushed(app, event)
            Backups.CreateBackup(app)
            Interaction.SwitchViewAndFocus(app,1,'View1Button');
        end

        % Callback function: View2Button, View2Menu
        function View2ButtonPushed(app, event)
            Backups.CreateBackup(app)
            Interaction.SwitchViewAndFocus(app,2,'View2Button');
        end

        % Callback function
        function Button_7Pushed(app, event)
            GUI.SwitchViewAndFocus(app,3,'Button_7');       
        end

        % Menu selected function: Labels3DMenu
        function Labels3DButtonPushed(app, event)
            GUI.DisableAllButtonsAndActions(app);
            Plotting.Labels3DView(app);
            GUI.RevertControlsStatus(app);
        end

        % Menu selected function: LabelsStatsMenu
        function LabelsstatsButtonPushed(app, event)
            Plotting.LabelsStatsPlot(app);
        end

        % Callback function
        function debugButtonPushed(app, event)
            Interaction.Debug(app)
        end

        % Callback function
        function debugButton2Pushed(app, event)
            Interaction.Debug2(app)
        end

        % Callback function
        function CreateBackupButtonPushed(app, event)
            Interaction.MakeBackup(app)
        end

        % Menu selected function: SwitchUserMenu
        function SwitchUserButtonPressed(app, event)
            Interaction.Save(app);       
            Interaction.PromptProfile(app)     
            IOUtils.PrepareStudy(app, app.filepath)
        end

        % Menu selected function: LoadDatabaseMenu
        function LoadSetButtonPushed(app, event)
            Database.PrepareDatabase(app)
        end

        % Menu selected function: PreviousStudyMenu
        function PrevStudyButtonPushed(app, event)
            Database.PrevStudy(app)
        end

        % Menu selected function: NextStudyMenu
        function NextStudyButtonPushed(app, event)
            Database.NextStudy(app)
        end

        % Value changed function: ROIBox
        function ROIBoxValueChanged(app, event)
            Interaction.ROIBoxChanged(app)
            
        end

        % Value changed function: AvailableStudiesListBox
        function AvailableStudiesListBoxValueChanged(app, event)
            Database.SwitchToStudy(app)
        end

        % Menu selected function: LoadLabelsMenu
        function LoadLabelsButtonPushed(app, event)
            %Loads a new segmentation for the current image.
            Interaction.LoadNewLabels(app)
        end

        % Callback function: EditPolygonButton, EditPolygonMenu
        function EditPolygon(app, event)
            %Toggles a mode where clicking / draggin near a polygon point 
            % enables the user to move that point.
            Interaction.EditPolygon(app)
        end

        % Menu selected function: MagicDraw3DMenu
        function MagicDraw3DToggle(app, event)
            value = app.MagicDraw3DMenu.Checked;
            app.drawing.magic_3d = value;
        end

        % Menu selected function: LoadROIpointsMenu
        function loadROIPointsButtonPushed(app, event)
          Interaction.LoadROIPoints(app)  
        end

        % Menu selected function: PointstoSegmentationMenu
        function PointsToSegmentationPressed(app, event)
            Interaction.PointsToSegmentation(app)
        end

        % Button pushed function: AlignLRButton
        function AlignLeftToRightAlignLRButton(app, event)
            Interaction.AlignLeftRight(app,'LR')
        end

        % Button pushed function: AlignRLButton
        function AlignRightToLeftButton(app, event)
            Interaction.AlignLeftRight(app,'RL')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'alberto@isi.uu.nl';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Text = 'Load';
            app.LoadLabelsMenu = uimenu(app.FileMenu);
            app.LoadLabelsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadLabelsButtonPushed, true);
            app.LoadLabelsMenu.Text = 'Load Labels';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveeditedsegmButtonPushed, true);
            app.SaveMenu.Text = 'Save';
            app.StudyMenu = uimenu(app.FileMenu);
            app.StudyMenu.Text = 'Study';
            app.SwitchUserMenu = uimenu(app.StudyMenu);
            app.SwitchUserMenu.MenuSelectedFcn = createCallbackFcn(app, @SwitchUserButtonPressed, true);
            app.SwitchUserMenu.Text = 'Switch User';
            app.LoadDatabaseMenu = uimenu(app.StudyMenu);
            app.LoadDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSetButtonPushed, true);
            app.LoadDatabaseMenu.Text = 'Load Database';
            app.NextStudyMenu = uimenu(app.StudyMenu);
            app.NextStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @NextStudyButtonPushed, true);
            app.NextStudyMenu.Text = 'Next Study';
            app.PreviousStudyMenu = uimenu(app.StudyMenu);
            app.PreviousStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @PrevStudyButtonPushed, true);
            app.PreviousStudyMenu.Text = 'Previous Study';
            app.ResetStudyMenu = uimenu(app.StudyMenu);
            app.ResetStudyMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetstudyButtonPushed, true);
            app.ResetStudyMenu.Text = 'Reset Study';
            app.LoadROIpointsMenu = uimenu(app.FileMenu);
            app.LoadROIpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @loadROIPointsButtonPushed, true);
            app.LoadROIpointsMenu.Text = 'Load ROI points';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.CoronalMenu = uimenu(app.ViewMenu);
            app.CoronalMenu.MenuSelectedFcn = createCallbackFcn(app, @CoronalButtonPushed, true);
            app.CoronalMenu.Text = 'Coronal';
            app.SagittalMenu = uimenu(app.ViewMenu);
            app.SagittalMenu.MenuSelectedFcn = createCallbackFcn(app, @SagittalButtonPushed, true);
            app.SagittalMenu.Text = 'Sagittal';
            app.AxialMenu = uimenu(app.ViewMenu);
            app.AxialMenu.MenuSelectedFcn = createCallbackFcn(app, @AxialButtonPushed, true);
            app.AxialMenu.Text = 'Axial';
            app.SwitchViewMenu = uimenu(app.ViewMenu);
            app.SwitchViewMenu.Text = 'Switch View';
            app.View1Menu = uimenu(app.SwitchViewMenu);
            app.View1Menu.MenuSelectedFcn = createCallbackFcn(app, @View1ButtonPushed, true);
            app.View1Menu.Text = 'View 1';
            app.View2Menu = uimenu(app.SwitchViewMenu);
            app.View2Menu.MenuSelectedFcn = createCallbackFcn(app, @View2ButtonPushed, true);
            app.View2Menu.Text = 'View 2';
            app.SwitchColoursMenu = uimenu(app.ViewMenu);
            app.SwitchColoursMenu.MenuSelectedFcn = createCallbackFcn(app, @ShufflecolorsButtonPushed, true);
            app.SwitchColoursMenu.Text = 'Switch Colours';
            app.AnalyseMenu = uimenu(app.UIFigure);
            app.AnalyseMenu.Text = 'Analyse';
            app.Labels3DMenu = uimenu(app.AnalyseMenu);
            app.Labels3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Labels3DButtonPushed, true);
            app.Labels3DMenu.Text = 'Labels 3D';
            app.LabelsStatsMenu = uimenu(app.AnalyseMenu);
            app.LabelsStatsMenu.MenuSelectedFcn = createCallbackFcn(app, @LabelsstatsButtonPushed, true);
            app.LabelsStatsMenu.Text = 'Labels Stats';
            app.DrawMenu = uimenu(app.UIFigure);
            app.DrawMenu.Text = 'Draw';
            app.LabelsMenu = uimenu(app.DrawMenu);
            app.LabelsMenu.Text = 'Labels';
            app.DrawPolygonMenu = uimenu(app.LabelsMenu);
            app.DrawPolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawpolygonButtonPushed, true);
            app.DrawPolygonMenu.Text = 'Draw Polygon';
            app.EditPolygonMenu = uimenu(app.LabelsMenu);
            app.EditPolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @EditPolygon, true);
            app.EditPolygonMenu.Text = 'Edit Polygon';
            app.MeasurementsMenu = uimenu(app.DrawMenu);
            app.MeasurementsMenu.Text = 'Measurements';
            app.LineMenu = uimenu(app.MeasurementsMenu);
            app.LineMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasureLineButtonPushed, true);
            app.LineMenu.Text = 'Line';
            app.EllipseMenu = uimenu(app.MeasurementsMenu);
            app.EllipseMenu.MenuSelectedFcn = createCallbackFcn(app, @MeasureAutoButtonPushed, true);
            app.EllipseMenu.Text = 'Ellipse';
            app.ClearMenu = uimenu(app.DrawMenu);
            app.ClearMenu.Text = 'Clear';
            app.UndoMenu = uimenu(app.ClearMenu);
            app.UndoMenu.MenuSelectedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoMenu.Text = 'Undo';
            app.RemoveSingleMenu = uimenu(app.ClearMenu);
            app.RemoveSingleMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDeleteButtonPushed, true);
            app.RemoveSingleMenu.Text = 'Remove Single';
            app.RemoveAllMenu = uimenu(app.ClearMenu);
            app.RemoveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteROIsALLButtonPushed, true);
            app.RemoveAllMenu.Text = 'Remove All';
            app.SelectionSensitivityMenu = uimenu(app.ClearMenu);
            app.SelectionSensitivityMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectionsensitivityButtonPushed, true);
            app.SelectionSensitivityMenu.Text = 'Selection Sensitivity';
            app.SegmentMenu = uimenu(app.UIFigure);
            app.SegmentMenu.Text = 'Segment';
            app.MagicDrawMenu = uimenu(app.SegmentMenu);
            app.MagicDrawMenu.MenuSelectedFcn = createCallbackFcn(app, @MagicdrawButtonPushed, true);
            app.MagicDrawMenu.Text = 'Magic Draw';
            app.MagicDraw3DMenu = uimenu(app.SegmentMenu);
            app.MagicDraw3DMenu.MenuSelectedFcn = createCallbackFcn(app, @MagicDraw3DToggle, true);
            app.MagicDraw3DMenu.Checked = 'on';
            app.MagicDraw3DMenu.Text = 'Magic Draw 3D';
            app.PointstoSegmentationMenu = uimenu(app.SegmentMenu);
            app.PointstoSegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @PointsToSegmentationPressed, true);
            app.PointstoSegmentationMenu.Text = 'Points to Segmentation';
            app.Settings = uimenu(app.UIFigure);
            app.Settings.Text = 'Settings';
            app.UIAxes1 = uiaxes(app.UIFigure);
            app.CoronalButton = uibutton(app.UIFigure, 'push');
            app.CoronalButton.ButtonPushedFcn = createCallbackFcn(app, @CoronalButtonPushed, true);
            app.CoronalButton.Enable = 'off';
            app.CoronalButton.Text = 'Coronal';
            app.SagittalButton = uibutton(app.UIFigure, 'push');
            app.SagittalButton.ButtonPushedFcn = createCallbackFcn(app, @SagittalButtonPushed, true);
            app.SagittalButton.Enable = 'off';
            app.SagittalButton.Text = 'Sagittal';
            app.AxialButton = uibutton(app.UIFigure, 'push');
            app.AxialButton.ButtonPushedFcn = createCallbackFcn(app, @AxialButtonPushed, true);
            app.AxialButton.Enable = 'off';
            app.AxialButton.Text = 'Axial';
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.Enable = 'off';
            app.MinvalSliderLabel = uilabel(app.UIFigure);
            app.MinvalSliderLabel.Text = 'Min val';
            app.MinvalSlider = uislider(app.UIFigure);
            app.MinvalSlider.ValueChangingFcn = createCallbackFcn(app, @MinvalSliderValueChanging, true);
            app.MinvalSlider.Enable = 'off';
            app.MaxvalSliderLabel = uilabel(app.UIFigure);
            app.MaxvalSliderLabel.Text = 'Max val';
            app.MaxvalSlider = uislider(app.UIFigure);
            app.MaxvalSlider.ValueChangingFcn = createCallbackFcn(app, @MaxvalSliderValueChanging, true);
            app.MaxvalSlider.Enable = 'off';
            app.ViewcontrolLabel = uilabel(app.UIFigure);
            app.ViewcontrolLabel.Text = 'View control';
            app.BrightnessandslicemgmtLabel = uilabel(app.UIFigure);
            app.BrightnessandslicemgmtLabel.Text = 'Brightness and slice mgmt.';
            app.SensitivitySliderLabel = uilabel(app.UIFigure);
            app.SensitivitySliderLabel.Text = 'Sensitivity';
            app.SensitivitySlider = uislider(app.UIFigure);
            app.SensitivitySlider.Limits = [0 10];
            app.SensitivitySlider.MajorTicks = [0 5 10];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.SensitivitySlider.Enable = 'off';
            app.SensitivitySlider.Value = 2;
            app.AvailableimagesListBoxLabel = uilabel(app.UIFigure);
            app.AvailableimagesListBoxLabel.Text = 'Available images';
            app.AvailableimagesListBox = uilistbox(app.UIFigure);
            app.AvailableimagesListBox.Items = {};
            app.AvailableimagesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableimagesListBoxValueChanged, true);
            app.AvailableimagesListBox.Value = {};
            app.MagicdrawoptionsLabel = uilabel(app.UIFigure);
            app.MagicdrawoptionsLabel.Text = 'Magic draw options';
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Enable = 'off';
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'Upper thresh', 'Lower thresh', 'Active contours', 'Active contours (negative)'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Enable = 'off';
            app.AlgorithmDropDown.Value = 'Upper thresh';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.SliceDecreaseButton = uibutton(app.UIFigure, 'push');
            app.SliceDecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @SliceDecreaseButtonPushed, true);
            app.SliceDecreaseButton.Enable = 'off';
            app.SliceDecreaseButton.Text = '-';
            app.SliceIncreaseButton = uibutton(app.UIFigure, 'push');
            app.SliceIncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @SliceIncreaseButtonPushed, true);
            app.SliceIncreaseButton.Enable = 'off';
            app.SliceIncreaseButton.Text = '+';
            app.Decrease4DButton = uibutton(app.UIFigure, 'push');
            app.Decrease4DButton.ButtonPushedFcn = createCallbackFcn(app, @Decrease4DButtonPushed, true);
            app.Decrease4DButton.Enable = 'off';
            app.Decrease4DButton.Text = '-';
            app.Increase4DButton = uibutton(app.UIFigure, 'push');
            app.Increase4DButton.ButtonPushedFcn = createCallbackFcn(app, @Increase4DButtonPushed, true);
            app.Increase4DButton.Enable = 'off';
            app.Increase4DButton.Text = '+';
            app.DSliderLabel = uilabel(app.UIFigure);
            app.DSliderLabel.Text = '4D';
            app.DSlider = uislider(app.UIFigure);
            app.DSlider.ValueChangingFcn = createCallbackFcn(app, @DSliderValueChanging, true);
            app.DSlider.Enable = 'off';
            app.View1Button = uibutton(app.UIFigure, 'push');
            app.View1Button.ButtonPushedFcn = createCallbackFcn(app, @View1ButtonPushed, true);
            app.View1Button.Enable = 'off';
            app.View1Button.Text = '1';
            app.View2Button = uibutton(app.UIFigure, 'push');
            app.View2Button.ButtonPushedFcn = createCallbackFcn(app, @View2ButtonPushed, true);
            app.View2Button.Enable = 'off';
            app.View2Button.Text = '2';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.ROIsLabel = uilabel(app.UIFigure);
            app.ROIsLabel.Text = 'ROIs';
            app.ROIBox = uilistbox(app.UIFigure);
            app.ROIBox.Items = {};
            app.ROIBox.ValueChangedFcn = createCallbackFcn(app, @ROIBoxValueChanged, true);
            app.ROIBox.Value = {};
            app.UIAxes1Label = uilabel(app.UIFigure);
            app.UIAxes1Label.Text = {'View 1'; ''};
            app.UIAxes2Label = uilabel(app.UIFigure);
            app.UIAxes2Label.Text = {'View 2'; ''};
            app.AvailableStudiesListBoxLabel = uilabel(app.UIFigure);
            app.AvailableStudiesListBoxLabel.Text = 'Available Studies';
            app.AvailableStudiesListBox = uilistbox(app.UIFigure);
            app.AvailableStudiesListBox.Items = {};
            app.AvailableStudiesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableStudiesListBoxValueChanged, true);
            app.AvailableStudiesListBox.Value = {};
            app.DrawPolygonButton = uibutton(app.UIFigure, 'push');
            app.DrawPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @DrawpolygonButtonPushed, true);
            app.DrawPolygonButton.Enable = 'off';
            app.DrawPolygonButton.Text = 'Draw Polygon';
            app.DrawLabel = uilabel(app.UIFigure);
            app.DrawLabel.Text = 'Draw';
            app.EditPolygonButton = uibutton(app.UIFigure, 'push');
            app.EditPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @EditPolygon, true);
            app.EditPolygonButton.Enable = 'off';
            app.EditPolygonButton.Text = 'Edit Polygon';
            app.AlignLRButton = uibutton(app.UIFigure, 'push');
            app.AlignLRButton.ButtonPushedFcn = createCallbackFcn(app, @AlignLeftToRightAlignLRButton, true);
            app.AlignLRButton.Enable = 'off';
            app.AlignLRButton.Text = '←';
            app.AlignRLButton = uibutton(app.UIFigure, 'push');
            app.AlignRLButton.ButtonPushedFcn = createCallbackFcn(app, @AlignRightToLeftButton, true);
            app.AlignRLButton.Enable = 'off';
            app.AlignRLButton.Text = '→';
            app.AlignLabel = uilabel(app.UIFigure);
            app.AlignLabel.Text = 'Align';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMSStudio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end