classdef ROIPrompt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SelectedLabel               matlab.ui.control.Label
        CancelButton                matlab.ui.control.Button
        OKButton                    matlab.ui.control.Button
        AddtoexistingROIPanel       matlab.ui.container.Panel
        AddToROIBox                 matlab.ui.control.ListBox
        CreateanewROIPanel          matlab.ui.container.Panel
        RemoveButton                matlab.ui.control.Button
        ROITypeButtonGroup          matlab.ui.container.ButtonGroup
        SubtractiveButton           matlab.ui.control.RadioButton
        AdditiveButton              matlab.ui.control.RadioButton
        AddButton                   matlab.ui.control.Button
        SpecifyotherEditField       matlab.ui.control.EditField
        SpecifyotherEditFieldLabel  matlab.ui.control.Label
        CreateNewROIBox             matlab.ui.control.ListBox
        FromlistLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp         % RMSstudio app that calls this one
        selectedROI     % ROI that was selected
        newROI          %Whether or not a new ROI is made (instead of an existing one)
    end
    
    methods (Access = public)
        
        function Show(app, existingROIs, savedROIs, renameQ)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
            drawnow


            if ~isempty(savedROIs)
                app.CreateNewROIBox.Items = savedROIs;
            end

            %when renaming, disable top window, don't add existing ROIs
            if renameQ
                app.AddtoexistingROIPanel.Enable = 'off';
                return
            end

            
            if ~isempty(existingROIs)
                app.AddToROIBox.Items = existingROIs;
            else
                app.AddToROIBox.Items = {};
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp       = mainApp;
            app.selectedROI   = [];
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: AddButton
        function AddROIPressed(app, event)
            val     = app.SpecifyotherEditField.Value;
            
            if isempty(val)
                return
            end

            %Check for illegal characters
            if ~isvarname(val)
                return
            end

            %create HTML from ROI name and additive / subtractive toggle
            addSub  = app.ROITypeButtonGroup.SelectedObject.Text;
            if strcmp(addSub, 'Additive')
                val = [val ' +'];
            else
                val = [val ' -'];
            end
            
            if any(ismember(app.CreateNewROIBox.Items, val))
                return
            end

            app.CreateNewROIBox.Items{end+1}    = val;
            app.CreateNewROIBox.Value           = val;
            app.selectedROI                     = val;
            app.newROI                          = true;
        end

        % Callback function: CancelButton, UIFigure
        function Cancel(app, event)
            
            try
                app.mainApp.ROIPromptSlot(...
                    app.CreateNewROIBox.Items, ...
                    [])
            catch
                %app may have been closed already
                closereq
            end

            app.UIFigure.Visible = 'off';
            drawnow
        end

        % Button pushed function: OKButton
        function OK(app, event)
            if isempty(app.selectedROI)
                return
            end
            
            app.mainApp.ROIPromptSlot(...
                app.CreateNewROIBox.Items, ...
                app.selectedROI, ...
                app.newROI)

            app.selectedROI             = '';
            app.SelectedLabel.Text      = 'Selected: None';
            app.AddToROIBox.Value       = {};
            app.CreateNewROIBox.Value   = {};
            app.UIFigure.Visible = 'off';
            drawnow
        end

        % Value changed function: CreateNewROIBox
        function NewROISelected(app, event)
            value = app.CreateNewROIBox.Value;
            app.AddToROIBox.Value   = {};
            app.selectedROI         = value;
            app.newROI              = true;
            app.SelectedLabel.Text  = ['Selected: ' value];
        end

        % Value changed function: AddToROIBox
        function ExistingROISelected(app, event)
            value = app.AddToROIBox.Value;
            app.CreateNewROIBox.Value   = {};
            app.selectedROI             = value;
            app.newROI                  = false;
            app.SelectedLabel.Text      = ['Selected: ' value];
        end

        % Button pushed function: RemoveButton
        function RemoveROI(app, event)
            val = app.CreateNewROIBox.Value;
            if isempty(val)
                return
            end

            for i=1:length(app.CreateNewROIBox.Items)
                if strcmp(val, app.CreateNewROIBox.Items{i})
                    app.CreateNewROIBox.Items(i) = [];
%                     app.CreateNewROIBox.Value = '';
                    break
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Cancel, true);
            app.CreateanewROIPanel = uipanel(app.UIFigure);
            app.CreateanewROIPanel.Title = 'Or, create a new ROI';
            app.FromlistLabel = uilabel(app.CreateanewROIPanel);
            app.FromlistLabel.Text = 'From list';
            app.CreateNewROIBox = uilistbox(app.CreateanewROIPanel);
            app.CreateNewROIBox.Items = {};
            app.CreateNewROIBox.ValueChangedFcn = createCallbackFcn(app, @NewROISelected, true);
            app.CreateNewROIBox.Value = {};
            app.SpecifyotherEditFieldLabel = uilabel(app.CreateanewROIPanel);
            app.SpecifyotherEditFieldLabel.Text = 'Specify other';
            app.SpecifyotherEditField = uieditfield(app.CreateanewROIPanel, 'text');
            app.AddButton = uibutton(app.CreateanewROIPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIPressed, true);
            app.AddButton.Text = 'Add';
            app.ROITypeButtonGroup = uibuttongroup(app.CreateanewROIPanel);
            app.ROITypeButtonGroup.Title = 'ROI Type';
            app.AdditiveButton = uiradiobutton(app.ROITypeButtonGroup);
            app.AdditiveButton.Text = 'Additive';
            app.AdditiveButton.Value = true;
            app.SubtractiveButton = uiradiobutton(app.ROITypeButtonGroup);
            app.SubtractiveButton.Text = 'Subtractive';
            app.RemoveButton = uibutton(app.CreateanewROIPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveROI, true);
            app.RemoveButton.Text = 'Remove';
            app.AddtoexistingROIPanel = uipanel(app.UIFigure);
            app.AddtoexistingROIPanel.Title = 'Add to existing ROI';
            app.AddToROIBox = uilistbox(app.AddtoexistingROIPanel);
            app.AddToROIBox.Items = {};
            app.AddToROIBox.ValueChangedFcn = createCallbackFcn(app, @ExistingROISelected, true);
            app.AddToROIBox.Value = {};
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OK, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectedLabel = uilabel(app.UIFigure);
            app.SelectedLabel.Text = 'Selected: None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIPrompt(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end