classdef SegManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ExporttocsvButton  matlab.ui.control.Button
        SegTable           matlab.ui.control.Table
        PatientTable       matlab.ui.control.Table
    end

    
    properties (Access = public)
        dbPath % Path to the database.json file that contains the info for 
                %the current dataset
        mainApp %application which launches the SegManager

        pTable  %Table with patient info
        sTable  %Table with segmentation infoGUI.UpdateProgressDialogue(app, 'Loading', 4/4)
        dlg     %progressdialogue
        sessionLst  % Keeps track of the session for each segmentation in STable
    end

    methods (Access = private)
        
        function GenerateDatabase(app)
            %if no database.json file exists, create one
            %Go over all folders

            app.dlg = uiprogressdlg(app.UIFigure, 'Title',...
                'Generating database');

            app.ResetDatabase();

            %Find all rmsstudio folders
            sessions = dir(fullfile(app.mainApp.datasetPath, ...
                '**/rmsstudio'));
            sessions = unique({sessions.folder}); %weird step...

            %Go over each scan session, add to pTable
            for i = 1:length(sessions)
                
                session         = sessions{i};
                [base, ~, ~]    = fileparts(session);
                [base, sessionName, ~] = fileparts(base);
                [~, pid, ~]            = fileparts(base);
                nSegs           = app.FindSegmentations(session);
                %also adds to sTable

                app.pTable = [app.pTable; ...
                {pid, sessionName, nSegs}];

                app.dlg.Value = i/length(sessions);
            end

            app.SegTable.Data = app.sTable;
            app.PatientTable.Data = app.pTable;    

            close(app.dlg)
        end

        function nSegs = FindSegmentations(app, path)
            %Find the paths to all the segmentation .json files located in 
            %subfolders of the current path. 
            %Adds profile, scan, segName, slices & comment to sTable
            %returns: amount of segmentations in path
            
            %find all *segmentation.json files

            segFiles = dir(fullfile(path, '**/*-segmentation.json'));
            for i = 1:length(segFiles)
                segfile = segFiles(i);

                [base, subf1, ~]    = fileparts(segfile.folder);
                [~, subf2, ~]       = fileparts(base);

                if strcmp(subf2, 'rmsstudio')     %no profile-subfolder
                    profile = '-';
                    scan    = subf1;
                else    %with profile subfolder
                    profile = subf1;
                    scan    = subf2;
                end

                name                = strrep(segfile.name, ...
                    '-segmentation.json', '');

                %load json
                fid  = fopen(fullfile(segfile.folder, segfile.name), 'r');
                txt  = fread(fid,inf);
                txt  = char(txt');
                fclose(fid);
                data = jsondecode(txt);
    
                if isfield(data, 'viewDim')
                    viewDim = data.viewDim;
                else
                    viewDim = 3;
                end

                if isfield(data, 'comment')
                    comment = data.comment;
                else
                    comment = '';
                end

                %find slices 
                points = data.points;
                slices = length(unique(points(:, viewDim)));

                %find volume
                if isfield(data, 'volume')
                    volume = data.volume;
                else
                    volume = '';
                end

                %find mean
                if isfield(data, 'mean')
                    meanVal = data.mean;
                else
                    meanVal = '';
                end

                %find std
                if isfield(data, 'std')
                    stdVal = data.std;
                else
                    stdVal = '';
                end

                %find 5th %
                if isfield(data, 'prctile5')
                    prctile5 = data.prctile5;
                else
                    prctile5 = '';
                end

                %find 25th %
                if isfield(data, 'prctile25')
                    prctile25 = data.prctile25;
                else
                    prctile25 = '';
                end

                %find 50th %
                if isfield(data, 'prctile50')
                    prctile50 = data.prctile50;
                else
                    prctile50 = '';
                end

                %find 75th %
                if isfield(data, 'prctile75')
                    prctile75 = data.prctile75;
                else
                    prctile75 = '';
                end

                %find 95th %
                if isfield(data, 'prctile95')
                    prctile95 = data.prctile95;
                else
                    prctile95 = '';
                end

                app.sTable = [app.sTable; ...
                    {profile, scan, name, slices, comment, volume, ...
                    meanVal, stdVal, prctile5, prctile25, prctile50, ...
                    prctile75, prctile95}];

                app.sessionLst = [app.sessionLst; path];
                
            end

            nSegs = length(segFiles);
        end

        function ResetDatabase(app)

            %Init tables
            app.pTable = table();
            app.pTable.Patient_ID = {};
            app.pTable.Session = {};
            app.pTable.Segmentations = {};

            app.sTable = table();
            app.sTable.Profile = {};
            app.sTable.Scan = {};
            app.sTable.Name = {};
            app.sTable.Slices = {};
            app.sTable.Comment = {};
            app.sTable.Volume = {};
            app.sTable.meanVal = {};
            app.sTable.stdVal = {};
            app.sTable.prctile5 = {};
            app.sTable.prctile25 = {};
            app.sTable.prctile50 = {};
            app.sTable.prctile75 = {};
            app.sTable.prctile95 = {};
            

            %init sessionList
            app.sessionLst = {};
        end

        function HighlightRows(app, idx)
            app.ResetHighlights()
            s = uistyle("BackgroundColor", [0.25, 0.25, 0.35]);
            addStyle(app.SegTable, s, "row", idx);
            scroll(app.SegTable, 'row', max(1, min(idx)))
        end

        function ResetHighlights(app)
            s = uistyle("BackgroundColor", [0.15, 0.15, 0.15]);
            addStyle(app.SegTable, s);
        end
    end
    
    methods (Access = public)
        
        function Show(app)
            app.UIFigure.Visible = 'off';
            app.ResetHighlights()
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
            drawnow

            app.GenerateDatabase()
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, databasePath)
            %todo, create database.json file, read it

            app.mainApp = mainApp;
            app.dbPath  = databasePath;

            app.PatientTable.FontUnits = 'Inches';
            app.PatientTable.FontSize = 1/8;
            app.SegTable.FontUnits = 'Inches';
            app.SegTable.FontSize = 1/8;

            app.UIFigure.Visible = 'off';
        end

        % Selection changed function: PatientTable
        function PatientTableSelectionChanged(app, event)
            selection   = app.PatientTable.Selection;
            
            pid         = app.pTable{selection, 1};
            session     = app.pTable{selection, 2};

            sessID      = fullfile(app.dbPath, pid, session, 'rmsstudio');

            highlightIdx= find(ismember(app.sessionLst, sessID));
            if ~isempty(highlightIdx)
                app.HighlightRows(highlightIdx)
            else
                app.ResetHighlights()
            end

        end

        % Selection changed function: SegTable
        function SegTableSelectionChanged(app, event)
            selection = app.SegTable.Selection;
        end

        % Close request function: UIFigure
        function Cancel(app, event)
            app.UIFigure.Visible = 'off';
            drawnow
        end

        % Button pushed function: ExporttocsvButton
        function ExportDataset(app, event)
            
            [file, path] = uiputfile('*.csv*','Save results', fullfile(app.dbPath, 'results.csv'));
            if isempty(file)
                return
            end
            
            patientArr= table2array(app.pTable);
            rows = sum([patientArr{:,3}]) + sum([patientArr{:,3}] == 0);
            columns = length(app.PatientTable.ColumnName) + length(app.SegTable.ColumnName);

            export = cell(rows, columns);
            counter = 1;

            %Loop over all patients, find each segmentation for each
            %patient. Add to one large cell array
            for i = 1:height(app.pTable)

                pid         = app.pTable{i, 1};
                session     = app.pTable{i, 2};
                sessID      = fullfile(app.dbPath, pid, session, 'rmsstudio');
                idx         = find(ismember(app.sessionLst, sessID));

                for ii = idx'
                    vals = table2cell([app.pTable(i,:), app.sTable(ii, :)]);
                    for iii = 1:length(vals)
                        export{counter, iii} = num2str(vals{iii});
                    end
                    counter = counter + 1;
                end

                if isempty(idx)
                    vals = [patientArr(i,:), repmat({'-'}, 1, length(app.SegTable.ColumnName))];
                    for iii = 1:length(vals)
                        export{counter, iii} = num2str(vals{iii});
                    end
                    counter = counter + 1;
                end
            end

            exportTable = cell2table(export, ...
                "VariableNames", ...
                [app.PatientTable.ColumnName', app.SegTable.ColumnName']);

            try
                writetable(exportTable, fullfile(path, file));
            catch e
                errordlg(e.message, 'file error')
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SegManager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Cancel, true);
            app.PatientTable = uitable(app.UIFigure);
            app.PatientTable.ColumnName = {'Patient_ID'; 'Session'; 'Segmentation'};
            app.PatientTable.RowName = {};
            app.PatientTable.SelectionType = 'row';
            app.PatientTable.ColumnEditable = [false false false];
            app.PatientTable.SelectionChangedFcn = createCallbackFcn(app, @PatientTableSelectionChanged, true);
            app.SegTable = uitable(app.UIFigure);
            app.SegTable.ColumnName = {'Profile'; 'Scan'; 'Name'; 'Slices'; 'Comment'; 'Volume (mL)'; 'Mean'; 'Std'; '5th %'; '25th %'; '50th %'; '75th %'; '95th %'};
            app.SegTable.RowName = {};
            app.SegTable.SelectionType = 'row';
            app.SegTable.SelectionChangedFcn = createCallbackFcn(app, @SegTableSelectionChanged, true);
            app.ExporttocsvButton = uibutton(app.UIFigure, 'push');
            app.ExporttocsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataset, true);
            app.ExporttocsvButton.Text = 'Export to .csv';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end