classdef AIHelper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AIHelperUIFigure             matlab.ui.Figure
        GridLayout3                  matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        TrainAITab                   matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        ExporttonnUnetformatButton   matlab.ui.control.Button
        LoadTensorFlownetworkButton  matlab.ui.control.Button
        LoadPyTorchnetworkButton     matlab.ui.control.Button
        CreatefilterButton           matlab.ui.control.Button
        StarttrainingButton          matlab.ui.control.Button
        SelectnoneButton             matlab.ui.control.Button
        SelectallButton              matlab.ui.control.Button
        NetworkLabel                 matlab.ui.control.Label
        MaskLabel                    matlab.ui.control.Label
        NetworkListBox               matlab.ui.control.ListBox
        MaskListBox                  matlab.ui.control.ListBox
        TableLabel                   matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        ApplyAITab                   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        datapath %path to dataset
        
        sTable % struct containing the segmentation values
        sTableIdx %which elements of the sTable are selected
        filter %used to filter the sTable

        maskTypes %all masks that have been found

        dlg %uidialogue
    end
    
    methods (Access = private)
        
        function GenerateDatabase(app)
            %Create a list of all segmentations in the current datapath
            %Also finds all mask types
            
            app.dlg = uiprogressdlg(app.AIHelperUIFigure, 'Title',...
                'Generating database');

            app.maskTypes = {};

            %Find all rmsstudio folders
            sessions = dir(fullfile(app.datapath, ...
                '**/rmsstudio'));
            sessions = unique({sessions.folder}); %weird step...

            %Go over each patient, 
            for i = 1:length(sessions)
                
                session                 = sessions{i};
                [base, ~, ~]            = fileparts(session);
                [base, sessionName, ~]  = fileparts(base);
                [~, patient, ~]             = fileparts(base);

                res           = app.FindSegmentedScans(session);
                for ii = 1:length(res)
                    scan = res{ii}{1};
                    profile = res{ii}{2};

                    if contains(patient, '_4')
                        a = 2;
                        a = a+1;
                    end

                    if ~isempty(app.sTable) & sum(all(ismember(app.sTable, ...
                            {patient, sessionName, scan, profile }), 2))
                        continue
                    end

                    app.sTable = [app.sTable; ...
                        {patient, sessionName, scan, profile }];

                    %add unique masks
                    if ~ismember(res{ii}(3), app.maskTypes)
                        app.maskTypes(end+1) = res{ii}(3);
                    end
                    
                end
                app.dlg.Value = i/length(sessions);
            end
            app.UITable.Data = app.sTable;

            close(app.dlg)

            app.sTableIdx = false(length(app.sTable), 1);
            app.MaskListBox.Items = app.maskTypes;
            app.MaskListBox.Value = app.maskTypes;
        end

        function res = FindSegmentedScans(~, path)
            %Find the paths to all the segmentation .json files located in 
            %subfolders of the current path. 
            %Adds profile, scan, segName, slices & comment to sTable
            %returns: amount of segmentations in path
            
            %find all *segmentation.json files

            res = {};

            segFiles = dir(fullfile(path, '**/*-segmentation.json'));
            for i = 1:length(segFiles)
                segfile = segFiles(i);

                [base, subf1, ~]    = fileparts(segfile.folder);
                [~, subf2, ~]       = fileparts(base);

                if strcmp(subf2, 'rmsstudio')     %no profile-subfolder
                    profile = '';
                    scan    = subf1;
                else    %with profile subfolder
                    profile = subf1;
                    scan    = subf2;
                end

                %find mask name
                mask = strrep(segfile.name, '-segmentation.json', '');

                res{end+1} = {scan, profile, mask};
                
            end
        end

        function HighlightRows(app)
            app.ResetHighlights()
            s = uistyle("BackgroundColor", [0.25, 0.25, 0.35]);
            addStyle(app.UITable, s, "row", find(app.sTableIdx));
        end

        function ResetHighlights(app)
            s = uistyle("BackgroundColor", [0.15, 0.15, 0.15]);
            addStyle(app.UITable, s);
        end
        
        function applyFilter(app)
            %Go over each element in the filter and apply it to the
            %listings in app.sTable
            %Multiple values are allowed per element, split by ; or ,

            %For include rules, at least one rule must apply
            %For exclude rules, all rules must apply

            %First, remove all whitespace from filters
            app.filter = strrep(app.filter, ' ', '');

            Idx = true(length(app.sTable), 1); %start with full idx

            %Patient must include
            if ~isempty(app.filter{1})
                ptntInclIdx = false(length(app.sTable), 1);
                ptntIncl = app.filter{1};
                for rule = strsplit(ptntIncl, {';', ','})
                    tmpIdx = contains(app.sTable(:,1), rule);
                    ptntInclIdx = ptntInclIdx | tmpIdx;  
                end
                Idx = Idx & ptntInclIdx;
            end

            %Patient must not include
            if ~isempty(app.filter{2})
                ptntExclIdx = true(length(app.sTable), 1);
                ptntExcl = app.filter{2};
                for rule = strsplit(ptntExcl, {';', ','})
                    tmpIdx = contains(app.sTable(:,1), rule);
                    ptntExclIdx = ptntExclIdx & tmpIdx;  % and operator, because they must all apply                  
                end
                Idx = Idx & ~ptntExclIdx;   %inverse, because these are all excluded
            end

            %session must include
            if ~isempty(app.filter{3})
                sessInclIdx = false(length(app.sTable), 1);
                sessIncl = app.filter{3};
                for rule = strsplit(sessIncl, {';', ','})
                    tmpIdx = contains(app.sTable(:,2), rule);
                    sessInclIdx = sessInclIdx | tmpIdx;  
                end
                Idx = Idx & sessInclIdx;
            end

            %Session must not include
            if ~isempty(app.filter{4})
                sessExclIdx = true(length(app.sTable), 1);
                sessExcl = app.filter{4};
                for rule = strsplit(sessExcl, {';', ','})
                    tmpIdx = contains(app.sTable(:,2), rule);
                    sessExclIdx = sessExclIdx & tmpIdx;       
                end
                Idx = Idx & ~sessExclIdx;   
            end

            %scan must include
            if ~isempty(app.filter{5})
                scanInclIdx = false(length(app.sTable), 1);
                scanIncl = app.filter{5};
                for rule = strsplit(scanIncl, {';', ','})
                    tmpIdx = contains(app.sTable(:,3), rule);
                    scanInclIdx = scanInclIdx | tmpIdx;  
                end
                Idx = Idx & scanInclIdx;
            end

            %scan must not include
            if ~isempty(app.filter{6})
                scanExclIdx = true(length(app.sTable), 1);
                scanExcl = app.filter{6};
                for rule = strsplit(scanExcl, {';', ','})
                    tmpIdx = contains(app.sTable(:,3), rule);
                    scanExclIdx = scanExclIdx & tmpIdx;  
                end
                Idx = Idx & ~scanExclIdx;   
            end

            %profile must include
            if ~isempty(app.filter{7})
                profileInclIdx = false(length(app.sTable), 1);
                profileIncl = app.filter{7};
                for rule = strsplit(profileIncl, {';', ','})
                    tmpIdx = contains(app.sTable(:,4), rule);
                    profileInclIdx = profileInclIdx | tmpIdx;  
                end
                Idx = Idx & profileInclIdx;
            end

            %profile must not include
            if ~isempty(app.filter{8})
                profileExclIdx = true(length(app.sTable), 1);
                profileExcl = app.filter{8};
                for rule = strsplit(profileExcl, {';', ','})
                    tmpIdx = contains(app.sTable(:,4), rule);
                    profileExclIdx = profileExclIdx & tmpIdx;  
                end
                Idx = Idx & ~profileExclIdx;   
            end


            disp(sum(Idx))
            app.sTableIdx = Idx;
            app.HighlightRows()
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datapath)
            app.datapath = datapath;
            app.GenerateDatabase()
        end

        % Selection changed function: UITable
        function SelectionChanged(app, event)
            selection = app.UITable.Selection(1);
            
            app.sTableIdx(selection) = ~app.sTableIdx(selection);
            app.HighlightRows()
        end

        % Button pushed function: SelectnoneButton
        function SelectNone(app, event)
            app.sTableIdx = false(length(app.sTable), 1);
            app.HighlightRows()
        end

        % Button pushed function: SelectallButton
        function SelectAll(app, event)
            app.sTableIdx = true(length(app.sTable), 1);
            app.HighlightRows()
        end

        % Button pushed function: CreatefilterButton
        function CreateFilter(app, event)
            if isempty(app.filter)
                app.filter = inputdlg({'Patient must include (put , / or ; between options, must include at least one)', ...
                            'Patient excludes (put , / or ; between options, must exclude all)', ...
                            'Session must include', ...
                            'Session excludes', ...
                            'Scan must include', ...
                            'Scan excludes', ...
                            'Profile must include', ...
                            'Profile excludes'}, ...
                            ...
                            'Create selection filter', ...
                            ...
                            [1 100; 1 100; 1 100; 1 100; ...
                            1 100; 1 100; 1 100; 1 100]);
            else
                app.filter = inputdlg({'Patient must include (put ,/; between options, must include at least one)', ...
                            'Patient excludes (put ,/; between options, must exclude all)', ...
                            'Session must include', ...
                            'Session excludes', ...
                            'Scan must include', ...
                            'Scan excludes', ...
                            'Profile must include', ...
                            'Profile excludes'}, ...
                            ...
                            'Create selection filter', ...
                            ...
                            [1 100; 1 100; 1 100; 1 100; ...
                            1 100; 1 100; 1 100; 1 100], ...
                            app.filter);
            end           

            app.applyFilter()
        end

        % Button pushed function: ExporttonnUnetformatButton
        function exportData(app, event)
            
            %progressdlg
            app.dlg = uiprogressdlg(app.AIHelperUIFigure, 'Title',...
                'Exporting selected files');
            
            %find folder 
            outpath = uigetdir(app.datapath, 'Select the destination folder');
            if ~exist(outpath, "dir")
                mkdir(outpath)
            end
            
            mkdir(fullfile(outpath, "imagesTr"))
            mkdir(fullfile(outpath, "labelsTr"))

            [~, datasetName, ~] = fileparts(app.datapath);

            imageCounter = 1;
            labels = app.MaskListBox.Value;

            %Iterate over selected rows
            selectedRows = find(app.sTableIdx);

            for i = 1:length(selectedRows)

                
                patient     = app.sTable{selectedRows(i), 1};
                sessionName = app.sTable{selectedRows(i), 2};
                scan        = app.sTable{selectedRows(i), 3};
                profile     = app.sTable{selectedRows(i), 4};

                %find&write image
                filepath = fullfile(app.datapath, patient, sessionName, 'rmsstudio', ...
                    strcat(scan, '.nii.gz'));
                if ~exist(filepath, "file")
                    filepath = fullfile(app.datapath, patient, sessionName, 'rmsstudio', ...
                    strcat(scan, '.nii'));
                    if ~exist(filepath, "file")
                        uialert(app.AIHelperUIFigure, ...
                            strcat("Can't find the following file: ", ...
                            newline, filepath))
                        continue
                    end
                end

                outfilepath = fullfile(outpath, "imagesTr", ...
                    strcat(datasetName, ...
                    sprintf('_%03d_0001', imageCounter), '.nii.gz'));

                copyfile(filepath, outfilepath)

                %find & write label(s)
                labelFiles = dir(fullfile(app.datapath, patient, sessionName, 'rmsstudio', ...
                    scan, profile, '*.nii.gz'));

                baseNii = load_untouch_nii(fullfile(labelFiles(1).folder, labelFiles(1).name));
                baseMask = zeros(size(baseNii.img));

                %Go over all labels, add to the emtpyMask with the value
                %corresponding to the order of the name in the 'labels'
                %table. e.g. if labels = [bone, muscle, tumor], then the
                %values will be [1,2,3]. Background is 0. 
                %masks are added lowest to highest value

                orderedLabelfiles = cell(length(labels), 1);

                %First get all masks in order
                for ii = 1:length(labelFiles)
                    labelFile = labelFiles(ii);
                    labelName = strrep(labelFile.name, '-segmentation.nii.gz', '');
                    value = find(contains(labels, labelName));
                    if isempty(value)
                        continue
                    end
                    orderedLabelfiles{value} = fullfile(labelFile.folder, labelFile.name);
                end
                
                %add to basemask
                for ii = 1:length(orderedLabelfiles)
                    labelFile = orderedLabelfiles{ii};
                    if isempty(labelFile)
                        continue
                    end
                    nii = load_untouch_nii(labelFile);
                    
                    idx = find(nii.img);
                    baseMask(idx) = nii.img(idx) * ii;
                end

                outfilepath = fullfile(outpath, "labelsTr", ...
                    strcat(datasetName, ...
                    sprintf('_%03d', imageCounter), '.nii.gz'));

                baseNii.img = baseMask;
                save_untouch_nii(baseNii, outfilepath)

                app.dlg.Value = i/length(selectedRows);
                imageCounter = imageCounter + 1;
            end

        close(app.dlg)

        %create .json -- do it manually because structs can't have numbers
        %as fields :'(
        
        jsonstr = "{";
        jsonstr = strcat(jsonstr, newline, '"channel_names": {', ...
            newline, '"0": MRI', newline, '},');
        jsonstr = strcat(jsonstr, newline, '"labels": {', newline, ...
            '"background": 0,');
        for i = 1:length(labels)
            label = labels{i};
            jsonstr = strcat(jsonstr, newline, '"', label, '": ', ...
                num2str(i), ',');
        end
        jsonstr = strcat(jsonstr, newline, '"numTraining": ', ...
            num2str(length(selectedRows)), ',');
        jsonstr = strcat(jsonstr, newline, '"file_ending": ".nii.gz"');
        
        fid = fopen(fullfile(outpath, 'dataset.json'), 'w');
        fprintf(fid,'%s',jsonstr);
        fclose(fid);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AIHelperUIFigure = uifigure('Visible', 'off');
            app.AIHelperUIFigure.Name = 'AI Helper';
            app.GridLayout3 = uigridlayout(app.AIHelperUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.TrainAITab = uitab(app.TabGroup);
            app.TrainAITab.Title = 'Train AI';
            app.GridLayout = uigridlayout(app.TrainAITab);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Patient'; 'Session'; 'Scan'; 'Profile'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @SelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.TableLabel = uilabel(app.GridLayout);
            app.TableLabel.Text = 'Select segmentations that should be included in the training';
            app.MaskListBox = uilistbox(app.GridLayout);
            app.MaskListBox.Multiselect = 'on';
            app.MaskListBox.Value = {'Item 1'};
            app.NetworkListBox = uilistbox(app.GridLayout);
            app.MaskLabel = uilabel(app.GridLayout);
            app.MaskLabel.Text = 'Select which masks to include (Ctrl + click for multiselect)';
            app.NetworkLabel = uilabel(app.GridLayout);
            app.NetworkLabel.Text = 'Select which network to use';
            app.SelectallButton = uibutton(app.GridLayout, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAll, true);
            app.SelectallButton.Text = 'Select all';
            app.SelectnoneButton = uibutton(app.GridLayout, 'push');
            app.SelectnoneButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNone, true);
            app.SelectnoneButton.Text = 'Select none';
            app.StarttrainingButton = uibutton(app.GridLayout, 'push');
            app.StarttrainingButton.Text = 'Start training';
            app.CreatefilterButton = uibutton(app.GridLayout, 'push');
            app.CreatefilterButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFilter, true);
            app.CreatefilterButton.Text = 'Create filter';
            app.LoadPyTorchnetworkButton = uibutton(app.GridLayout, 'push');
            app.LoadPyTorchnetworkButton.Text = 'Load PyTorch network';
            app.LoadTensorFlownetworkButton = uibutton(app.GridLayout, 'push');
            app.LoadTensorFlownetworkButton.Text = 'Load TensorFlow network';
            app.ExporttonnUnetformatButton = uibutton(app.GridLayout, 'push');
            app.ExporttonnUnetformatButton.ButtonPushedFcn = createCallbackFcn(app, @exportData, true);
            app.ExporttonnUnetformatButton.Text = 'Export to nnUnet format';
            app.ApplyAITab = uitab(app.TabGroup);
            app.ApplyAITab.Title = 'Apply AI';
            % Show the figure after all components are created
            app.AIHelperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AIHelper(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AIHelperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AIHelperUIFigure)
        end
    end
end