classdef ContourPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ContourSelecterUIFigure     matlab.ui.Figure
        AcceptButton                matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        ContourDisplayOptionsPanel  matlab.ui.container.Panel
        OpacitySlider               matlab.ui.control.Slider
        OpacitySliderLabel          matlab.ui.control.Label
        OpacityLabel                matlab.ui.control.Label
        ColorLabel                  matlab.ui.control.Label
        PickColorButton             matlab.ui.control.Button
        ContourOptionsPanel         matlab.ui.container.Panel
        SensitivityLabel            matlab.ui.control.Label
        ContourvolumeButtonGroup    matlab.ui.container.ButtonGroup
        Button3D                    matlab.ui.control.RadioButton
        Button2D                    matlab.ui.control.RadioButton
        ContourMethodDropDown       matlab.ui.control.DropDown
        ContourMethodDropDownLabel  matlab.ui.control.Label
        SensitiviySlider            matlab.ui.control.Slider
        SensitiviySliderLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp % TURUQOISE app
        hitx % x position of starting point
        hity % y position of starting point
        axID % axID in the mainApp

        segmentation = [] % the mask (either 2D or 3D)
        
        %GUI stuff
        color = [0, 1, 1];
        opacity = 0.4;
        sensitivity = 0.2;
        
        
    end
    
    methods (Access = public)
        
        function Open(app)
            movegui(app.ContourSelecterUIFigure,"center")
            app.ContourSelecterUIFigure.Visible = 'on';
        end

        function Close(app)

            Graphics.ResetTmpROI(app.mainApp, app.axID)
            app.mainApp.drawing.mode = 0;
            GUI.ResetCursor(app.mainApp);

            app.ContourSelecterUIFigure.Visible = 'off';
            app.segmentation = [];
        end
        
        function PosSelected(app, hitx, hity, axID)
            app.hitx = hitx;
            app.hity = hity;
            app.axID = axID;

            app.RunContour()
        end
    end
    
    methods (Access = private)
        
        function RunContour(app)
            if app.Button2D.Value   %2D
                app.segmentation = Segmentation.Seg2D( ...
                    app.mainApp, app.hitx, app.hity, ...
                    app.sensitivity, app.ContourMethodDropDown.Value);
            else    %3D
                app.segmentation = Segmentation.Seg3D( ...
                    app.mainApp, app.hitx, app.hity, ...
                    app.sensitivity, app.ContourMethodDropDown.Value);
            end
        
            %fix 3D
            app.UpdateContour()

        end
        
        function UpdateContour(app)

            if isempty(app.segmentation)
                return
            end

            app.mainApp.tmpMasks{app.axID} = app.segmentation;

            Graphics.DrawTmpROIInAxis( ...
                app.mainApp, app.axID)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            app.ContourSelecterUIFigure.Visible = 'off';
            
        end

        % Button pushed function: PickColorButton
        function PickColor(app, event)
            c = uisetcolor(app.color);
            if isempty(c)
                return
            end
            app.color = c;
            app.ColorLabel.BackgroundColor = c;
            app.mainApp.tmpMaskColor = c;

            app.UpdateContour()
        end

        % Value changed function: OpacitySlider
        function OpacityChanged(app, event)
            value = app.OpacitySlider.Value;
            app.opacity = value;
            app.OpacityLabel.Text = num2str(value);
            app.mainApp.tmpMaskOpacity = value;

            app.UpdateContour()
        end

        % Button pushed function: AcceptButton
        function FinalizeContour(app, event)
            Segmentation.FinalizeContour(app.mainApp, app.segmentation)
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            app.Close()
        end

        % Value changed function: SensitiviySlider
        function SensitivityChanged(app, event)
            value = app.SensitiviySlider.Value;
            app.sensitivity = value;
            app.SensitivityLabel.Text = num2str(value);

            app.RunContour()
        end

        % Close request function: ContourSelecterUIFigure
        function CloseFromX(app, event)
            app.Close()      
        end

        % Value changed function: ContourMethodDropDown
        function MethodChanged(app, event)
            value = app.ContourMethodDropDown.Value;
            app.RunContour()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ContourSelecterUIFigure = uifigure('Visible', 'off');
            app.ContourSelecterUIFigure.Name = 'Contour Selecter';
            app.ContourSelecterUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseFromX, true);
            app.ContourOptionsPanel = uipanel(app.ContourSelecterUIFigure);
            app.ContourOptionsPanel.Title = 'Contour Options';
            app.SensitiviySliderLabel = uilabel(app.ContourOptionsPanel);
            app.SensitiviySliderLabel.Text = 'Sensitiviy';
            app.SensitiviySlider = uislider(app.ContourOptionsPanel);
            app.SensitiviySlider.Limits = [0 1];
            app.SensitiviySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivityChanged, true);
            app.SensitiviySlider.Value = 0.2;
            app.ContourMethodDropDownLabel = uilabel(app.ContourOptionsPanel);
            app.ContourMethodDropDownLabel.Text = 'Contour Method';
            app.ContourMethodDropDown = uidropdown(app.ContourOptionsPanel);
            app.ContourMethodDropDown.Items = {'LowerThreshold', 'HigherThreshold', 'ChanVese', 'ChanVeseComplement'};
            app.ContourMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodChanged, true);
            app.ContourMethodDropDown.Value = 'LowerThreshold';
            app.ContourvolumeButtonGroup = uibuttongroup(app.ContourOptionsPanel);
            app.ContourvolumeButtonGroup.Title = 'Contour volume';
            app.Button2D = uiradiobutton(app.ContourvolumeButtonGroup);
            app.Button2D.Text = '2D';
            app.Button2D.Value = true;
            app.Button3D = uiradiobutton(app.ContourvolumeButtonGroup);
            app.Button3D.Text = '3D';
            app.SensitivityLabel = uilabel(app.ContourOptionsPanel);
            app.SensitivityLabel.Text = '0.2';
            app.ContourDisplayOptionsPanel = uipanel(app.ContourSelecterUIFigure);
            app.ContourDisplayOptionsPanel.Title = 'Contour Display Options';
            app.PickColorButton = uibutton(app.ContourDisplayOptionsPanel, 'push');
            app.PickColorButton.ButtonPushedFcn = createCallbackFcn(app, @PickColor, true);
            app.PickColorButton.Text = 'Pick Color';
            app.ColorLabel = uilabel(app.ContourDisplayOptionsPanel);
            app.ColorLabel.Text = '';
            app.OpacityLabel = uilabel(app.ContourDisplayOptionsPanel);
            app.OpacityLabel.Text = '0.4';
            app.OpacitySliderLabel = uilabel(app.ContourDisplayOptionsPanel);
            app.OpacitySliderLabel.Text = 'Opacity';
            app.OpacitySlider = uislider(app.ContourDisplayOptionsPanel);
            app.OpacitySlider.Limits = [0 1];
            app.OpacitySlider.MajorTicks = [0 1];
            app.OpacitySlider.ValueChangedFcn = createCallbackFcn(app, @OpacityChanged, true);
            app.OpacitySlider.Value = 0.4;
            app.CancelButton = uibutton(app.ContourSelecterUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.AcceptButton = uibutton(app.ContourSelecterUIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeContour, true);
            app.AcceptButton.Text = 'Accept';
            % Show the figure after all components are created
            app.ContourSelecterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContourPicker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ContourSelecterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ContourSelecterUIFigure)
        end
    end
end