classdef PatientReport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientreportUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Profile2LB             matlab.ui.control.ListBox
        Session2Label          matlab.ui.control.Label
        Scans2LB               matlab.ui.control.ListBox
        Session2LB             matlab.ui.control.ListBox
        ProfileLabel           matlab.ui.control.Label
        Profile1LB             matlab.ui.control.ListBox
        ScansLabel             matlab.ui.control.Label
        Session1Label          matlab.ui.control.Label
        Scans1LB               matlab.ui.control.ListBox
        Session1LB             matlab.ui.control.ListBox
        UIAxes1                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        datapath % path to the data for the current patient
        privateFolderName % name of the private folder where the TURQOUISE app stores all important files
        cmap    % colormap
        mainApp
        dlg     %progress dialogue
        %data

        im1 % nifti image for session #1
        im2 % nifti image for session #2

        segmentations1 % list of mask names
        segmentations2 % list of maks names

        masks1 % cell array of masks for session #1
        masks2 % cell array of masks for session #2

        vals1 % cell array of pixel values for session #1
        vals2 % cell array of pixel values for session #2

        minX = Inf;
        maxX = 0;
    end
    
    methods (Access = private)
        
        function scans = findScans(app, session)
        %Find all scans within the session that have segmentations

            scans = {};
            %find all folders in datapath/session/privateFolder
            scanpath = fullfile(app.datapath, ...
                session, ...
                app.privateFolderName);
            folders = dir(scanpath);
            folders = folders([folders.isdir]);
            folders = folders(~ismember({folders.name},{'.','..'}));

            %Remove empty folders
            for i = 1:length(folders)
                folder = folders(i);
                items = dir(fullfile(folder.folder, folder.name));
                items = items(~ismember({items.name},{'.','..'}));
                if isempty(items)
                continue
                end
                scans{end+1} = folder.name;
            end
        end
        
        function profiles = findProfiles(app, session, scan)
            %List all profiles for the current scan and profile

            profilePath = fullfile(app.datapath, ...
                session, ...
                app.privateFolderName, ...
                scan);
            profiles = dir(profilePath);
            profiles = profiles([profiles.isdir]);
            profiles = profiles(~ismember({profiles.name},{'.','..'}));
            profiles = {profiles.name};

            %if scans exist within the folder, add an empty profile
            scans = dir(fullfile(profilePath, '*.nii*'));
            if ~isempty(scans)
                profiles{end+1} = '';
            end
            
        end
        
        function segmentations = findSegmentations(app, session, scan, profile)
            %List all segmentations for the current session, scan, and
            %profile

            segmentationPath = fullfile(app.datapath, ...
                session, ...
                app.privateFolderName, ...
                scan, ...
                profile);
            segmentations = dir(fullfile(segmentationPath, '*.json'));
            segmentations = {segmentations.name};

            for i = 1:length(segmentations)
                segmentations{i} = strrep(segmentations{i}, ...
                    '-segmentation.json', '');
            end
            
        end
        
        function updateSession1Graph(app)
            % Draws the histogram & mean value for each segmentation

            legends = {};

            for i = 1:length(app.masks1)

                segName = app.segmentations1{i};
                cVal = mod(sum(double(upper(segName))), 256);
                color = app.cmap(cVal, :);

                maskArr = app.masks1{i}.img;
                vals = app.im1.img(find(maskArr));

                minx = min(vals);
                if minx < app.minX
                    app.minX = minx;
                end
                maxx = max(vals);
                if maxx > app.maxX
                    app.maxX = maxx;
                end

                histogram(app.UIAxes1, vals, 50, ...
                    'DisplayStyle','stairs', ...
                    'Normalization', 'countdensity',...
                    EdgeColor=color);

                hold(app.UIAxes1, 'on')
                xline(app.UIAxes1, ...
                    mean(vals), ...
                    '-', ...
                    {segName, num2str(round(mean(vals),2))}, ...
                    Color=color)

                legends{end+1} = segName;
            end

            app.UIAxes1.YLimMode = 'auto';

            legend(app.UIAxes1, legends)
            hold(app.UIAxes1, 'off')
        end
        
        function updateSession2Graph(app)

            legends = {};

            for i = 1:length(app.masks2)

                segName = app.segmentations2{i};
                cVal = mod(sum(double(upper(segName))), 256);
                color = app.cmap(cVal, :);

                maskArr = app.masks2{i}.img;
                vals = app.im2.img(find(maskArr));

                minx = min(vals);
                if minx < app.minX
                    app.minX = minx;
                end
                maxx = max(vals);
                if maxx > app.maxX
                    app.maxX = maxx;
                end

                histogram(app.UIAxes2, vals, 50, ...
                    'DisplayStyle','stairs', ...
                    'Normalization', 'countdensity',...
                    EdgeColor=color);

                hold(app.UIAxes2, 'on')
                xline(app.UIAxes2, ...
                    mean(vals), ...
                    '-', ...
                    {segName, num2str(round(mean(vals),2))}, ...
                    Color=color)

                legends{end+1} = segName;
            end

            app.UIAxes2.XLim = app.UIAxes1.XLim;
            app.UIAxes2.YLimMode = 'auto';

            legend(app.UIAxes2, legends)
            hold(app.UIAxes2, 'off')
            
        end

    end

    methods (Access = public)

        function Run(app, datapath, privateFolderName)

            app.datapath = datapath;
            app.privateFolderName = privateFolderName;

            app.PatientreportUIFigure.Visible = 'off';
            movegui(app.PatientreportUIFigure,"center")
            app.PatientreportUIFigure.Visible = 'on';
            drawnow

            app.dlg = uiprogressdlg(app.PatientreportUIFigure, 'Title',...
                'Loading');

            %First, load all necessary data

            %find imaging sessions
            sessions = dir(app.datapath);
            sessions = sessions(~ismember({sessions.name},{'.','..'}));

            sessions = {sessions.name};
            app.Session1LB.Items = sessions;
            app.Session1LB.Value = sessions(1);

            app.Session2LB.Items = sessions;
            app.Session1LB.Value = sessions(end);

            app.dlg.Value = 0.05;

            %load scans etc.
            app.Session1Changed(app)
            app.dlg.Value = 0.55;
            app.Session2Changed(app)
            app.dlg.Value = 1;
            pause(0.3)

            close(app.dlg)

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            app.mainApp = mainApp;
            
            app.cmap = jet(256);
            app.PatientreportUIFigure.Visible = 'off';


        end

        % Value changed function: Profile1LB
        function Profile1Changed(app, event)

            %load masks
            masks = dir(fullfile(app.datapath, ...
                app.Session1LB.Value, ...
                app.privateFolderName, ...
                app.Scans1LB.Value, ...
                app.Profile1LB.Value, ...
                '*.nii*'));

            app.masks1 = {};
            for mask = masks'
                app.masks1{end+1} = load_untouch_nii( ...
                    fullfile(mask.folder, mask.name));
            end

            app.segmentations1 = app.findSegmentations(...
                app.Session1LB.Value, ...
                app.Scans1LB.Value, ...
                app.Profile1LB.Value);


            app.updateSession1Graph()
            
        end

        % Value changed function: Profile2LB
        function Profile2Changed(app, event)
            masks = dir(fullfile(app.datapath, ...
                app.Session2LB.Value, ...
                app.privateFolderName, ...
                app.Scans2LB.Value, ...
                app.Profile2LB.Value, ...
                '*.nii*'));

            app.masks2 = {};
            for mask = masks'
                app.masks2{end+1} = load_untouch_nii( ...
                    fullfile(mask.folder, mask.name));
            end

            app.segmentations2 = app.findSegmentations(...
                app.Session2LB.Value, ...
                app.Scans2LB.Value, ...
                app.Profile2LB.Value);

            app.updateSession2Graph()
            
        end

        % Value changed function: Scans1LB
        function Scans1Changed(app, event)
            
            %load im1
            scan = dir(fullfile(app.datapath, ...
                app.Session1LB.Value, ...
                app.privateFolderName, ...
                [app.Scans1LB.Value '.nii*']));

            app.im1 = load_untouch_nii(fullfile(scan.folder, scan.name));

            %update profiles
            profiles = findProfiles(app, ...
                app.Session1LB.Value, ...
                app.Scans1LB.Value);

            app.Profile1LB.Items = profiles;
            app.Profile1LB.Value = profiles(1);

            app.Profile1Changed(app)
            
        end

        % Value changed function: Scans2LB
        function Scans2Changed(app, event)
            %load im2
            scan = dir(fullfile(app.datapath, ...
                app.Session2LB.Value, ...
                app.privateFolderName, ...
                [app.Scans2LB.Value '.nii*']));

            app.im2 = load_untouch_nii(fullfile(scan.folder, scan.name));

            %update profiles
            
            profiles = findProfiles(app, ...
                app.Session2LB.Value, ...
                app.Scans2LB.Value);
            app.Profile2LB.Items = profiles;
            app.Profile2LB.Value = profiles(1);

            app.Profile2Changed(app)
            
        end

        % Value changed function: Session1LB
        function Session1Changed(app, event)
            scans = findScans(app, app.Session1LB.Value);
            if isempty(scans)
                return
            end

            app.Scans1LB.Items = scans;
            app.Scans1LB.Value = scans(1);

            app.Scans1Changed(app)
            
        end

        % Value changed function: Session2LB
        function Session2Changed(app, event)
            scans = findScans(app, app.Session2LB.Value);
            if isempty(scans)
                return
            end

            app.Scans2LB.Items = scans;
            app.Scans2LB.Value = scans(1);

            app.Scans2Changed(app)
            
        end

        % Close request function: PatientreportUIFigure
        function Hide(app, event)
            app.PatientreportUIFigure.Visible = 'off';
            drawnow            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientreportUIFigure = uifigure('Visible', 'off');
            app.PatientreportUIFigure.Name = 'Patient report';
            app.PatientreportUIFigure.CloseRequestFcn = createCallbackFcn(app, @Hide, true);
            app.GridLayout = uigridlayout(app.PatientreportUIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Session #2')
            xlabel(app.UIAxes2, 'Pixel value')
            ylabel(app.UIAxes2, 'Normalized count')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YTick = [];
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, 'Session #1')
            xlabel(app.UIAxes1, 'Pixel Value')
            ylabel(app.UIAxes1, 'Normalized count')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.YTick = [];
            app.Session1LB = uilistbox(app.GridLayout);
            app.Session1LB.ValueChangedFcn = createCallbackFcn(app, @Session1Changed, true);
            app.Scans1LB = uilistbox(app.GridLayout);
            app.Scans1LB.ValueChangedFcn = createCallbackFcn(app, @Scans1Changed, true);
            app.Session1Label = uilabel(app.GridLayout);
            app.Session1Label.Text = 'Session #1';
            app.ScansLabel = uilabel(app.GridLayout);
            app.ScansLabel.Text = 'Scans';
            app.Profile1LB = uilistbox(app.GridLayout);
            app.Profile1LB.ValueChangedFcn = createCallbackFcn(app, @Profile1Changed, true);
            app.ProfileLabel = uilabel(app.GridLayout);
            app.ProfileLabel.Text = 'Profile';
            app.Session2LB = uilistbox(app.GridLayout);
            app.Session2LB.ValueChangedFcn = createCallbackFcn(app, @Session2Changed, true);
            app.Scans2LB = uilistbox(app.GridLayout);
            app.Scans2LB.ValueChangedFcn = createCallbackFcn(app, @Scans2Changed, true);
            app.Session2Label = uilabel(app.GridLayout);
            app.Session2Label.Text = 'Session #2';
            app.Profile2LB = uilistbox(app.GridLayout);
            app.Profile2LB.ValueChangedFcn = createCallbackFcn(app, @Profile2Changed, true);
            % Show the figure after all components are created
            app.PatientreportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatientReport(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientreportUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientreportUIFigure)
        end
    end
end